classdef DataEntryV2 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    TabGroup                      matlab.ui.container.TabGroup
    AddActionsTab                 matlab.ui.container.Tab
    StatusLamp                    matlab.ui.control.Lamp
    StatusLampLabel               matlab.ui.control.Label
    Detail2ListBox                matlab.ui.control.ListBox
    Detail2ListBoxLabel           matlab.ui.control.Label
    Detail1ListBox                matlab.ui.control.ListBox
    Detail1ListBoxLabel           matlab.ui.control.Label
    TeamListBox                   matlab.ui.control.ListBox
    TeamListBoxLabel              matlab.ui.control.Label
    NotesEditField                matlab.ui.control.EditField
    NotesEditFieldLabel           matlab.ui.control.Label
    ActionListBox                 matlab.ui.control.ListBox
    ActionListBoxLabel            matlab.ui.control.Label
    PlayerListBox                 matlab.ui.control.ListBox
    PlayerListBoxLabel            matlab.ui.control.Label
    AddtoActionsButton            matlab.ui.control.Button
    SaveDataTab                   matlab.ui.container.Tab
    DatafileNameEditFieldLabel    matlab.ui.control.Label
    DatafileNameEditField         matlab.ui.control.EditField
    VariableNameGameActionsLabel  matlab.ui.control.Label
    SaveVariabletoFileButton      matlab.ui.control.Button
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: AddtoActionsButton
    function add2Variable(app, event)
      
      % get base workspace var into local
      GameActions = evalin('base','GameActions');
      idx = size(GameActions,1)+1;
      
      % arrange inputs from App
      entry = {};
      entry{1} = app.TeamListBox.Value;
      entry{2} = app.PlayerListBox.Value;
      entry{3} = app.ActionListBox.Value;
      entry{4} = app.Detail1ListBox.Value;
      entry{5} = app.Detail2ListBox.Value;
      entry{6} = app.NotesEditField.Value;
      
      % add entry into database
      GameActions(idx,:) = entry;
      assignin('base','GameActions',GameActions);
      
      % reset some inputs fields after entering data
      app.NotesEditField.Value = "";
      
      % flash a light for visual
      for i = 1:7
        app.StatusLamp.Color = [1,0,0];
        pause(0.08)
        app.StatusLamp.Color = [0.8,0.8,0.8];
        pause(0.06)
      end
      app.StatusLamp.Color = [0,1,0];
      
    end

    % Button pushed function: SaveVariabletoFileButton
    function save2File(app, event)
      proj = matlab.project.currentProject;
      GameActions = evalin('base','GameActions');            % get base workspace var into local
%       filename = ['./Data/' app.DatafileNameEditField.Value];
%       save(filename,"GameActions")
      save(strcat(proj.RootFolder,"\Data\",app.DatafileNameEditField.Value),"GameActions")
    end

    % Value changed function: ActionListBox
    function changeDetails(app, event)
      value = app.ActionListBox.Value;
      switch value
        case 'Pass'
          app.Detail1ListBox.Items =  {'Regular', 'Threat', 'Assist', 'Missed'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Shoot'
          app.Detail1ListBox.Items =  {'FreeThrow', 'Layup', 'Close', '2ShortL', '2ShortTop', '2ShortR', '2LongLC', '2LongLW', '2LongTop', '2LongRW', '2LongRC', '3LC', '3LW', '3Top', '3RW', '3RC'};
          app.Detail2ListBox.Items =  {'miss', 'made'};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Rebound'
          app.Detail1ListBox.Items =  {'Offensive', 'Defensive'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Defense'
          app.Detail1ListBox.Items =  {'Steal', 'Block', 'Shot Contest', 'Shot Allowed'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Turnover'
          app.Detail1ListBox.Items =  {'Pass', 'Travel', 'Dribble', 'Bound', 'Foul'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Other'
          app.Detail1ListBox.Items =  {'Off-ball screen', 'Screen assist'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.TabGroup = uitabgroup(app.UIFigure);
      app.AddActionsTab = uitab(app.TabGroup);
      app.AddActionsTab.Title = 'Add Actions';
      app.AddtoActionsButton = uibutton(app.AddActionsTab, 'push');
      app.AddtoActionsButton.ButtonPushedFcn = createCallbackFcn(app, @add2Variable, true);
      app.AddtoActionsButton.Text = 'Add to Actions';
      app.PlayerListBoxLabel = uilabel(app.AddActionsTab);
      app.PlayerListBoxLabel.Text = 'Player';
      app.PlayerListBox = uilistbox(app.AddActionsTab);
      app.PlayerListBox.Items = {'Alex_YXY ', 'Andrew_Du ', 'Archie_ZYQ ', 'BK_FYP ', 'Bob_BKZ ', 'Bobo_PB ', 'Daniel_XZY ', 'David_HQC ', 'Hou_HF ', 'Jam_LTJ ', 'Jim_ZYJ ', 'Kevin_MZQ ', 'Michael_CBC ', 'Neo_LWQ ', 'Paras_WXH ', 'Tyzer_TZX ', 'Wan_Yuxin ', 'Will_ZJW ', 'Yuan_XC ', 'CUBA_LCY ', 'Jim_XQH ', 'Chione_XCY ', 'Nicole_LXR ', 'Tang_YF ', 'Ma_LY ', 'Ken_BYX ', 'Tony_ZBR ', 'Allen_GHH ', 'Zhu_XY ', 'Bobby_ZCB '};
      app.PlayerListBox.Value = 'Alex_YXY ';
      app.ActionListBoxLabel = uilabel(app.AddActionsTab);
      app.ActionListBoxLabel.Text = 'Action';
      app.ActionListBox = uilistbox(app.AddActionsTab);
      app.ActionListBox.Items = {'Pass', 'Shoot', 'Rebound', 'Turnover', 'Defense', 'Other'};
      app.ActionListBox.ValueChangedFcn = createCallbackFcn(app, @changeDetails, true);
      app.ActionListBox.Value = 'Pass';
      app.NotesEditFieldLabel = uilabel(app.AddActionsTab);
      app.NotesEditFieldLabel.Text = 'Notes';
      app.NotesEditField = uieditfield(app.AddActionsTab, 'text');
      app.TeamListBoxLabel = uilabel(app.AddActionsTab);
      app.TeamListBoxLabel.Text = 'Team';
      app.TeamListBox = uilistbox(app.AddActionsTab);
      app.TeamListBox.Items = {'A', 'B', 'C', 'D'};
      app.TeamListBox.Value = 'A';
      app.Detail1ListBoxLabel = uilabel(app.AddActionsTab);
      app.Detail1ListBoxLabel.Text = 'Detail1';
      app.Detail1ListBox = uilistbox(app.AddActionsTab);
      app.Detail1ListBox.Items = {'Regular', 'Threat', 'Assist', 'Missed'};
      app.Detail1ListBox.Value = 'Regular';
      app.Detail2ListBoxLabel = uilabel(app.AddActionsTab);
      app.Detail2ListBoxLabel.Text = 'Detail2';
      app.Detail2ListBox = uilistbox(app.AddActionsTab);
      app.Detail2ListBox.Items = {' ', ''};
      app.Detail2ListBox.Value = ' ';
      app.StatusLampLabel = uilabel(app.AddActionsTab);
      app.StatusLampLabel.Text = 'Status';
      app.StatusLamp = uilamp(app.AddActionsTab);
      app.SaveDataTab = uitab(app.TabGroup);
      app.SaveDataTab.Title = 'Save Data';
      app.SaveVariabletoFileButton = uibutton(app.SaveDataTab, 'push');
      app.SaveVariabletoFileButton.ButtonPushedFcn = createCallbackFcn(app, @save2File, true);
      app.SaveVariabletoFileButton.Text = 'Save Variable to File';
      app.VariableNameGameActionsLabel = uilabel(app.SaveDataTab);
      app.VariableNameGameActionsLabel.Text = ' Variable Name:  GameActions';
      app.DatafileNameEditField = uieditfield(app.SaveDataTab, 'text');
      app.DatafileNameEditField.Value = 'ActionTableTest.mat';
      app.DatafileNameEditFieldLabel = uilabel(app.SaveDataTab);
      app.DatafileNameEditFieldLabel.Text = 'Datafile Name:';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DataEntryV2

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end