classdef DataEntryV5 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                     matlab.ui.Figure
    TabGroup                     matlab.ui.container.TabGroup
    LoadNewDataTab               matlab.ui.container.Tab
    ORLabel                      matlab.ui.control.Label
    ContinueWorkingLabel         matlab.ui.control.Label
    StartfromScratchLabel        matlab.ui.control.Label
    NewActionDataButton          matlab.ui.control.Button
    LoadorcreatenewdatawilloverwritecurrentworkLabel  matlab.ui.control.Label
    ActionFilesListBox_2         matlab.ui.control.ListBox
    ActionFilesListBox_2Label    matlab.ui.control.Label
    LoadActionDataButton         matlab.ui.control.Button
    ListActionFilesButton_2      matlab.ui.control.Button
    AssignPlayersTab             matlab.ui.container.Tab
    AddtoTeamBButton             matlab.ui.control.Button
    AddtoTeamAButton             matlab.ui.control.Button
    DeleteLastButtonB            matlab.ui.control.Button
    DeleteLastButtonA            matlab.ui.control.Button
    TeamBListBox                 matlab.ui.control.ListBox
    TeamBListBoxLabel            matlab.ui.control.Label
    TeamAListBox                 matlab.ui.control.ListBox
    TeamAListBoxLabel            matlab.ui.control.Label
    FullRosterListBox            matlab.ui.control.ListBox
    FullRosterListBoxLabel       matlab.ui.control.Label
    AddActionsTab                matlab.ui.container.Tab
    Image                        matlab.ui.control.Image
    EditActionsButton            matlab.ui.control.Button
    TeamNameLabel                matlab.ui.control.Label
    PlayerNameLabel              matlab.ui.control.Label
    TeamBListBox_2               matlab.ui.control.ListBox
    TeamBListBox_2Label          matlab.ui.control.Label
    TeamAListBox_2               matlab.ui.control.ListBox
    TeamAListBox_2Label          matlab.ui.control.Label
    DeleteLastEntryButton        matlab.ui.control.Button
    StatusLamp                   matlab.ui.control.Lamp
    StatusLampLabel              matlab.ui.control.Label
    Detail2ListBox               matlab.ui.control.ListBox
    Detail2ListBoxLabel          matlab.ui.control.Label
    Detail1ListBox               matlab.ui.control.ListBox
    Detail1ListBoxLabel          matlab.ui.control.Label
    NotesEditField               matlab.ui.control.EditField
    NotesEditFieldLabel          matlab.ui.control.Label
    ActionListBox                matlab.ui.control.ListBox
    ActionListBoxLabel           matlab.ui.control.Label
    AddtoActionsButton           matlab.ui.control.Button
    SaveDataTab                  matlab.ui.container.Tab
    DatafileNameEditFieldLabel   matlab.ui.control.Label
    DatafileNameEditField        matlab.ui.control.EditField
    startwithActionegAction0909Game2matLabel  matlab.ui.control.Label
    VariableNameGameActionsfixedLabel  matlab.ui.control.Label
    SaveDatatoFileButton         matlab.ui.control.Button
    GenerateStatsTab             matlab.ui.container.Tab
    SaveactiondatabeforegeneratestatsLabel  matlab.ui.control.Label
    Step3Label                   matlab.ui.control.Label
    Step2Label                   matlab.ui.control.Label
    Step1Label                   matlab.ui.control.Label
    GenerateStatsButton          matlab.ui.control.Button
    StatsFilenameEditField       matlab.ui.control.EditField
    StatsFilenameEditFieldLabel  matlab.ui.control.Label
    ListActionFilesButton        matlab.ui.control.Button
    ActionFilesListBox           matlab.ui.control.ListBox
    ActionFilesListBoxLabel      matlab.ui.control.Label
    ShotMapTab                   matlab.ui.container.Tab
    Step3Label_2                 matlab.ui.control.Label
    FullShotMapButton            matlab.ui.control.Button
    PlayerShotMapButton          matlab.ui.control.Button
    PlayerListListBox            matlab.ui.control.ListBox
    PlayerListListBoxLabel       matlab.ui.control.Label
    Step2Label_2                 matlab.ui.control.Label
    LoadActionFilesButton        matlab.ui.control.Button
    ShotmapforwholeteamoreachplayerLabel  matlab.ui.control.Label
    Step1Label_2                 matlab.ui.control.Label
    ListActionFilesButton_3      matlab.ui.control.Button
    ActionFilesListBox_3         matlab.ui.control.ListBox
    ActionFilesListBox_3Label    matlab.ui.control.Label
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: ListActionFilesButton, 
    % ListActionFilesButton_2, ListActionFilesButton_3
    function ListActionFiles(app, event)
      proj = matlab.project.currentProject;
      list = dir(strcat(proj.RootFolder,"/Data/Action*"));
      list = list(~cellfun('isempty', {list.date})); 
      filenames = {};
      for i = 1:length(list)
        tmplist = list(i);
        filenames{i} = tmplist.name;
      end
      app.ActionFilesListBox.Items = filenames;
      app.ActionFilesListBox.Value = app.ActionFilesListBox.Items(1);
      app.ActionFilesListBox_2.Items = filenames;
      app.ActionFilesListBox_2.Value = app.ActionFilesListBox_2.Items(1);      
      app.ActionFilesListBox_3.Items = filenames;
      app.ActionFilesListBox_3.Value = app.ActionFilesListBox_3.Items(1);      
    end

    % Button pushed function: LoadActionDataButton
    function loadActionData(app, event)
      load(app.ActionFilesListBox_2.Value)
      assignin('base','GameActions',GameActions);
    end

    % Button pushed function: NewActionDataButton
    function newActionData(app, event)
      init_GameActions;
      assignin('base','GameActions',GameActions);
    end

    % Button pushed function: AddtoTeamAButton
    function add2TeamA(app, event)
      n = length(app.TeamAListBox.Items);
      entry = app.FullRosterListBox.Value;
      m = length(entry);
      for i = 1:m
        app.TeamAListBox.Items{n+i-1} = entry{i};
      end
      app.TeamAListBox.Items{n+m} = ' ';
      app.TeamAListBox.Value = app.TeamAListBox.Items{n+m};
      app.TeamAListBox_2.Items = app.TeamAListBox.Items;
    end

    % Button pushed function: DeleteLastButtonA
    function Delete2TeamA(app, event)
      n = length(app.TeamAListBox.Items);
      if n > 1
        app.TeamAListBox.Items(n-1) = [];
      end
      app.TeamAListBox_2.Items = app.TeamAListBox.Items;
    end

    % Button pushed function: AddtoTeamBButton
    function add2TeamB(app, event)
      n = length(app.TeamBListBox.Items);
      entry = app.FullRosterListBox.Value;
      m = length(entry);
      for i = 1:m
        app.TeamBListBox.Items{n+i-1} = entry{i};
      end
      app.TeamBListBox.Items{n+m} = ' ';
      app.TeamBListBox.Value = app.TeamBListBox.Items{n+m};
      app.TeamBListBox_2.Items = app.TeamBListBox.Items;
    end

    % Button pushed function: DeleteLastButtonB
    function Delete2TeamB(app, event)
      n = length(app.TeamBListBox.Items);
      if n > 1
        app.TeamBListBox.Items(n-1) = [];
      end
      app.TeamBListBox_2.Items = app.TeamBListBox.Items;
    end

    % Value changed function: TeamAListBox_2
    function selectTeamAPlayer(app, event)
      value = app.TeamAListBox_2.Value;
      app.PlayerNameLabel.Text = value;
      app.TeamNameLabel.Text = "Team A";
      app.TeamBListBox_2.Value = ' ';
      try
        app.Image.ImageSource = strcat(value,".jpg");
      catch ME
      end
    end

    % Value changed function: TeamBListBox_2
    function selectTeamBPlayer(app, event)
      value = app.TeamBListBox_2.Value;
      app.PlayerNameLabel.Text = value;
      app.TeamNameLabel.Text = "Team B";
      app.TeamAListBox_2.Value = ' ';
      try
        app.Image.ImageSource = strcat(value,".jpg");
      catch ME
      end
    end

    % Value changed function: ActionListBox
    function selectAction(app, event)
      value = app.ActionListBox.Value;
      switch value
        case 'Pass'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Regular', 'Threat', 'Assist', 'Missed'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Make Shot'
          app.Detail1ListBox.FontSize = 12;
          app.Detail1ListBox.Items =  {'Layup', 'Close', '2ShortL', '2ShortTop', '2ShortR', '2LongLC', '2LongLW', '2LongTop', '2LongRW', '2LongRC', '3LC', '3LW', '3Top', '3RW', '3RC', 'FreeThrow'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Miss Shot'
          app.Detail1ListBox.FontSize = 12;
          app.Detail1ListBox.Items =  {'Layup', 'Close', '2ShortL', '2ShortTop', '2ShortR', '2LongLC', '2LongLW', '2LongTop', '2LongRW', '2LongRC', '3LC', '3LW', '3Top', '3RW', '3RC', 'FreeThrow'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Rebound'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Defensive', 'Offensive'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Defense'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Steal', 'Block', 'Shot Contest', 'Shot Allowed'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Turnover'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Pass', 'Travel', 'Dribble', 'Bound', 'Foul'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Other'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Off-ball screen', 'Screen assist'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
      end
    end

    % Button pushed function: AddtoActionsButton
    function add2Variable(app, event)
      
      % get base workspace var into local
      GameActions = evalin('base','GameActions');
      idx = size(GameActions,1)+1;
      
      % arrange inputs from App
      entry = {};
      entry{1} = app.TeamNameLabel.Text;
      entry{2} = app.PlayerNameLabel.Text;
      entry{3} = app.ActionListBox.Value;
      entry{4} = app.Detail1ListBox.Value;
      entry{5} = app.Detail2ListBox.Value;
      entry{6} = app.NotesEditField.Value;
      
      % add entry into database
      GameActions(idx,:) = entry;
      assignin('base','GameActions',GameActions);
      
      % reset some inputs fields after entering data
      app.NotesEditField.Value = "";
      
      % flash a light for visual
      for i = 1:3
        app.StatusLamp.Color = [0.8,0.8,0.8];
        pause(0.05)
        app.StatusLamp.Color = [0,0.6,1];
        pause(0.15)
      end
      app.StatusLamp.Color = [0,1,0];
      
      if app.ActionListBox.Value == "Miss Shot"
        app.ActionListBox.Value = 'Rebound';
        app.Detail1ListBox.FontSize = 14;
        app.Detail1ListBox.Items =  {'Defensive', 'Offensive'};
        app.Detail2ListBox.Items =  {' '};
        app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
        app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
      end

      if app.ActionListBox.Value == "Make Shot" | app.ActionListBox.Value == "Turnover" 
        app.ActionListBox.Value = 'Pass';
        app.Detail1ListBox.FontSize = 14;
        app.Detail1ListBox.Items =  {'Regular', 'Threat', 'Assist', 'Missed'};
        app.Detail2ListBox.Items =  {' '};
        app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
        app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        if app.TeamNameLabel.Text == "Team A"
          app.TeamNameLabel.Text = 'Team B';
          app.TeamBListBox_2.Value = app.TeamBListBox_2.Items(1);
          app.PlayerNameLabel.Text = app.TeamBListBox_2.Items(1);
          app.TeamAListBox_2.Value = ' ';
        else
          app.TeamNameLabel.Text = 'Team A';
          app.TeamAListBox_2.Value = app.TeamAListBox_2.Items(1);
          app.PlayerNameLabel.Text = app.TeamAListBox_2.Items(1);
          app.TeamBListBox_2.Value = ' ';
        end
      end
      
      if app.Detail1ListBox.Value == "Assist"
        app.ActionListBox.Value = 'Make Shot';
        app.Detail1ListBox.FontSize = 12;
        app.Detail1ListBox.Items =  {'Layup', 'Close', '2ShortL', '2ShortTop', '2ShortR', '2LongLC', '2LongLW', '2LongTop', '2LongRW', '2LongRC', '3LC', '3LW', '3Top', '3RW', '3RC', 'FreeThrow'};
        app.Detail2ListBox.Items =  {' '};
        app.Detail1ListBox.Value = app.Detail1ListBox.Items(2);
        app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        app.TeamAListBox_2.Value = ' ';
        app.TeamBListBox_2.Value = ' ';
      end
      
    end

    % Button pushed function: DeleteLastEntryButton
    function DeleteLastEntry(app, event)
      % get base workspace var into local
      GameActions = evalin('base','GameActions');
      n = height(GameActions);
      if n > 1
        % delete last entry
        GameActions(end,:) = [];
        % update back into workspace
        assignin('base','GameActions',GameActions);
      end
      % flash a light for visual
      for i = 1:2
        app.StatusLamp.Color = [0.8,0.8,0.8];
        pause(0.05)
        app.StatusLamp.Color = [1,0,0];
        pause(0.15)
      end
      app.StatusLamp.Color = [0,1,0];
    end

    % Button pushed function: EditActionsButton
    function OpenGameActions(app, event)
      openvar('GameActions')
    end

    % Button pushed function: SaveDatatoFileButton
    function save2File(app, event)
      proj = matlab.project.currentProject;
      GameActions = evalin('base','GameActions');    % get base workspace var into local
      save(strcat(proj.RootFolder,"/Data/",app.DatafileNameEditField.Value),"GameActions")
    end

    % Button pushed function: GenerateStatsButton
    function generateStats(app, event)
      filelist = app.ActionFilesListBox.Value;
      outfile  = app.StatsFilenameEditField.Value;
      func_GenerateStats(filelist,outfile);
%       func_GenerateShotMap(filelist,outfile);
    end

    % Button pushed function: LoadActionFilesButton
    function loadMultipleFiles(app, event)
      filelist = app.ActionFilesListBox_3.Value;
      n = length(filelist);
      load(filelist{1});
      tmpActions = GameActions;
      for i = 2:n
        load(filelist{i})
        tmpActions = [tmpActions; GameActions];  %#ok<AGROW>
      end
      GameActions = tmpActions;
      assignin('base','GameActions',GameActions);
      varPlayer = unique(GameActions.Player);
      app.PlayerListListBox.Items = varPlayer;
      app.PlayerListListBox.Value = app.PlayerListListBox.Items(1);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.TabGroup = uitabgroup(app.UIFigure);
      app.LoadNewDataTab = uitab(app.TabGroup);
      app.LoadNewDataTab.Title = 'Load/New Data';
      app.ListActionFilesButton_2 = uibutton(app.LoadNewDataTab, 'push');
      app.ListActionFilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ListActionFiles, true);
      app.ListActionFilesButton_2.Text = 'List Action Files';
      app.LoadActionDataButton = uibutton(app.LoadNewDataTab, 'push');
      app.LoadActionDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadActionData, true);
      app.LoadActionDataButton.Text = 'Load Action Data';
      app.ActionFilesListBox_2Label = uilabel(app.LoadNewDataTab);
      app.ActionFilesListBox_2Label.Text = 'Action Files';
      app.ActionFilesListBox_2 = uilistbox(app.LoadNewDataTab);
      app.ActionFilesListBox_2.Items = {};
      app.ActionFilesListBox_2.Value = {};
      app.LoadorcreatenewdatawilloverwritecurrentworkLabel = uilabel(app.LoadNewDataTab);
      app.LoadorcreatenewdatawilloverwritecurrentworkLabel.Text = 'Load or create new data will overwrite current work!!!';
      app.NewActionDataButton = uibutton(app.LoadNewDataTab, 'push');
      app.NewActionDataButton.ButtonPushedFcn = createCallbackFcn(app, @newActionData, true);
      app.NewActionDataButton.Text = 'New Action Data';
      app.StartfromScratchLabel = uilabel(app.LoadNewDataTab);
      app.StartfromScratchLabel.Text = 'Start from Scratch';
      app.ContinueWorkingLabel = uilabel(app.LoadNewDataTab);
      app.ContinueWorkingLabel.Text = 'Continue Working';
      app.ORLabel = uilabel(app.LoadNewDataTab);
      app.ORLabel.Text = 'OR';
      app.AssignPlayersTab = uitab(app.TabGroup);
      app.AssignPlayersTab.Title = 'Assign Players';
      app.FullRosterListBoxLabel = uilabel(app.AssignPlayersTab);
      app.FullRosterListBoxLabel.Text = 'Full Roster';
      app.FullRosterListBox = uilistbox(app.AssignPlayersTab);
      app.FullRosterListBox.Items = {'Alex_YXY ', 'Allen_GHH ', 'Andrew_Du ', 'Archie_ZYQ ', 'BK_FYP ', 'Bob_BKZ ', 'Bobby_ZCB', 'Bobo_PB ', 'Chione_XCY ', 'CUBA_LCY ', 'Daniel_XZY ', 'David_HQC ', 'Hou_Hanfu', 'Jam_LTJ ', 'JimBig_ZYJ ', 'JimLit_XQH ', 'Ken_BYX ', 'Kevin_MZQ ', 'Ma_Liyan', 'Michael_CBC ', 'Neo_LWQ ', 'Nicole_LXR ', 'Paras_WXH ', 'Tang_Yifeng ', 'Tony_ZBR ', 'Tyzer_TZX ', 'Wan_Yuxin ', 'Will_ZJW ', 'Yuan_Xincheng ', 'Zhu_Xinyi', ''};
      app.FullRosterListBox.Multiselect = 'on';
      app.FullRosterListBox.Value = {'Alex_YXY '};
      app.TeamAListBoxLabel = uilabel(app.AssignPlayersTab);
      app.TeamAListBoxLabel.Text = 'Team A';
      app.TeamAListBox = uilistbox(app.AssignPlayersTab);
      app.TeamAListBox.Items = {' '};
      app.TeamAListBox.Value = ' ';
      app.TeamBListBoxLabel = uilabel(app.AssignPlayersTab);
      app.TeamBListBoxLabel.Text = 'Team B';
      app.TeamBListBox = uilistbox(app.AssignPlayersTab);
      app.TeamBListBox.Items = {' '};
      app.TeamBListBox.Value = ' ';
      app.DeleteLastButtonA = uibutton(app.AssignPlayersTab, 'push');
      app.DeleteLastButtonA.ButtonPushedFcn = createCallbackFcn(app, @Delete2TeamA, true);
      app.DeleteLastButtonA.Text = 'Delete Last';
      app.DeleteLastButtonB = uibutton(app.AssignPlayersTab, 'push');
      app.DeleteLastButtonB.ButtonPushedFcn = createCallbackFcn(app, @Delete2TeamB, true);
      app.DeleteLastButtonB.Text = 'Delete Last';
      app.AddtoTeamAButton = uibutton(app.AssignPlayersTab, 'push');
      app.AddtoTeamAButton.ButtonPushedFcn = createCallbackFcn(app, @add2TeamA, true);
      app.AddtoTeamAButton.Text = 'Add to Team A';
      app.AddtoTeamBButton = uibutton(app.AssignPlayersTab, 'push');
      app.AddtoTeamBButton.ButtonPushedFcn = createCallbackFcn(app, @add2TeamB, true);
      app.AddtoTeamBButton.Text = 'Add to Team B';
      app.AddActionsTab = uitab(app.TabGroup);
      app.AddActionsTab.Title = 'Add Actions';
      app.AddtoActionsButton = uibutton(app.AddActionsTab, 'push');
      app.AddtoActionsButton.ButtonPushedFcn = createCallbackFcn(app, @add2Variable, true);
      app.AddtoActionsButton.Text = 'Add to Actions';
      app.ActionListBoxLabel = uilabel(app.AddActionsTab);
      app.ActionListBoxLabel.Text = 'Action';
      app.ActionListBox = uilistbox(app.AddActionsTab);
      app.ActionListBox.Items = {'Pass', 'Make Shot', 'Miss Shot', 'Rebound', 'Turnover', 'Defense', 'Other'};
      app.ActionListBox.ValueChangedFcn = createCallbackFcn(app, @selectAction, true);
      app.ActionListBox.Value = 'Pass';
      app.NotesEditFieldLabel = uilabel(app.AddActionsTab);
      app.NotesEditFieldLabel.Text = 'Notes';
      app.NotesEditField = uieditfield(app.AddActionsTab, 'text');
      app.Detail1ListBoxLabel = uilabel(app.AddActionsTab);
      app.Detail1ListBoxLabel.Text = 'Detail1';
      app.Detail1ListBox = uilistbox(app.AddActionsTab);
      app.Detail1ListBox.Items = {'Regular', 'Threat', 'Assist', 'Missed'};
      app.Detail1ListBox.Value = 'Regular';
      app.Detail2ListBoxLabel = uilabel(app.AddActionsTab);
      app.Detail2ListBoxLabel.Text = 'Detail2';
      app.Detail2ListBox = uilistbox(app.AddActionsTab);
      app.Detail2ListBox.Items = {' '};
      app.Detail2ListBox.Value = ' ';
      app.StatusLampLabel = uilabel(app.AddActionsTab);
      app.StatusLampLabel.Text = 'Status';
      app.StatusLamp = uilamp(app.AddActionsTab);
      app.DeleteLastEntryButton = uibutton(app.AddActionsTab, 'push');
      app.DeleteLastEntryButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastEntry, true);
      app.DeleteLastEntryButton.Text = {'Delete '; 'Last Entry'};
      app.TeamAListBox_2Label = uilabel(app.AddActionsTab);
      app.TeamAListBox_2Label.Text = 'Team A';
      app.TeamAListBox_2 = uilistbox(app.AddActionsTab);
      app.TeamAListBox_2.Items = {};
      app.TeamAListBox_2.ValueChangedFcn = createCallbackFcn(app, @selectTeamAPlayer, true);
      app.TeamAListBox_2.Value = {};
      app.TeamBListBox_2Label = uilabel(app.AddActionsTab);
      app.TeamBListBox_2Label.Text = 'Team B';
      app.TeamBListBox_2 = uilistbox(app.AddActionsTab);
      app.TeamBListBox_2.Items = {};
      app.TeamBListBox_2.ValueChangedFcn = createCallbackFcn(app, @selectTeamBPlayer, true);
      app.TeamBListBox_2.Value = {};
      app.PlayerNameLabel = uilabel(app.AddActionsTab);
      app.PlayerNameLabel.Text = 'Player Name';
      app.TeamNameLabel = uilabel(app.AddActionsTab);
      app.TeamNameLabel.Text = 'Team Name';
      app.EditActionsButton = uibutton(app.AddActionsTab, 'push');
      app.EditActionsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGameActions, true);
      app.EditActionsButton.Text = {'Edit '; 'Actions'};
      app.Image = uiimage(app.AddActionsTab);
      app.SaveDataTab = uitab(app.TabGroup);
      app.SaveDataTab.Title = 'Save Data';
      app.SaveDatatoFileButton = uibutton(app.SaveDataTab, 'push');
      app.SaveDatatoFileButton.ButtonPushedFcn = createCallbackFcn(app, @save2File, true);
      app.SaveDatatoFileButton.Text = 'Save Data to File';
      app.VariableNameGameActionsfixedLabel = uilabel(app.SaveDataTab);
      app.VariableNameGameActionsfixedLabel.Text = 'Variable Name: GameActions (fixed)';
      app.startwithActionegAction0909Game2matLabel = uilabel(app.SaveDataTab);
      app.startwithActionegAction0909Game2matLabel.Text = ' start with "Action", e.g. Action0909Game2.mat';
      app.DatafileNameEditField = uieditfield(app.SaveDataTab, 'text');
      app.DatafileNameEditField.Value = 'ActionDateContent.mat';
      app.DatafileNameEditFieldLabel = uilabel(app.SaveDataTab);
      app.DatafileNameEditFieldLabel.Text = 'Datafile Name:';
      app.GenerateStatsTab = uitab(app.TabGroup);
      app.GenerateStatsTab.Title = 'Generate Stats';
      app.ActionFilesListBoxLabel = uilabel(app.GenerateStatsTab);
      app.ActionFilesListBoxLabel.Text = 'Action Files';
      app.ActionFilesListBox = uilistbox(app.GenerateStatsTab);
      app.ActionFilesListBox.Items = {};
      app.ActionFilesListBox.Multiselect = 'on';
      app.ActionFilesListBox.Value = {};
      app.ListActionFilesButton = uibutton(app.GenerateStatsTab, 'push');
      app.ListActionFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ListActionFiles, true);
      app.ListActionFilesButton.Text = 'List Action Files';
      app.StatsFilenameEditFieldLabel = uilabel(app.GenerateStatsTab);
      app.StatsFilenameEditFieldLabel.Text = 'Stats Filename';
      app.StatsFilenameEditField = uieditfield(app.GenerateStatsTab, 'text');
      app.StatsFilenameEditField.Value = 'StatsTest';
      app.GenerateStatsButton = uibutton(app.GenerateStatsTab, 'push');
      app.GenerateStatsButton.ButtonPushedFcn = createCallbackFcn(app, @generateStats, true);
      app.GenerateStatsButton.Text = 'Generate Stats';
      app.Step1Label = uilabel(app.GenerateStatsTab);
      app.Step1Label.Text = 'Step 1';
      app.Step2Label = uilabel(app.GenerateStatsTab);
      app.Step2Label.Text = 'Step 2';
      app.Step3Label = uilabel(app.GenerateStatsTab);
      app.Step3Label.Text = 'Step 3';
      app.SaveactiondatabeforegeneratestatsLabel = uilabel(app.GenerateStatsTab);
      app.SaveactiondatabeforegeneratestatsLabel.Text = 'Save action data before generate stats!!!';
      app.ShotMapTab = uitab(app.TabGroup);
      app.ShotMapTab.Title = 'Shot Map';
      app.ActionFilesListBox_3Label = uilabel(app.ShotMapTab);
      app.ActionFilesListBox_3Label.Text = 'Action Files';
      app.ActionFilesListBox_3 = uilistbox(app.ShotMapTab);
      app.ActionFilesListBox_3.Items = {};
      app.ActionFilesListBox_3.Multiselect = 'on';
      app.ActionFilesListBox_3.Value = {};
      app.ListActionFilesButton_3 = uibutton(app.ShotMapTab, 'push');
      app.ListActionFilesButton_3.ButtonPushedFcn = createCallbackFcn(app, @ListActionFiles, true);
      app.ListActionFilesButton_3.Text = 'List Action Files';
      app.Step1Label_2 = uilabel(app.ShotMapTab);
      app.Step1Label_2.Text = 'Step 1';
      app.ShotmapforwholeteamoreachplayerLabel = uilabel(app.ShotMapTab);
      app.ShotmapforwholeteamoreachplayerLabel.Text = 'Shot map for whole team or each player';
      app.LoadActionFilesButton = uibutton(app.ShotMapTab, 'push');
      app.LoadActionFilesButton.ButtonPushedFcn = createCallbackFcn(app, @loadMultipleFiles, true);
      app.LoadActionFilesButton.Text = 'Load Action Files';
      app.Step2Label_2 = uilabel(app.ShotMapTab);
      app.Step2Label_2.Text = 'Step 2';
      app.PlayerListListBoxLabel = uilabel(app.ShotMapTab);
      app.PlayerListListBoxLabel.Text = 'Player List';
      app.PlayerListListBox = uilistbox(app.ShotMapTab);
      app.PlayerListListBox.Items = {' '};
      app.PlayerListListBox.Multiselect = 'on';
      app.PlayerListListBox.Value = {' '};
      app.PlayerShotMapButton = uibutton(app.ShotMapTab, 'push');
      app.PlayerShotMapButton.Text = 'Player Shot Map';
      app.FullShotMapButton = uibutton(app.ShotMapTab, 'push');
      app.FullShotMapButton.Text = 'Full Shot Map';
      app.Step3Label_2 = uilabel(app.ShotMapTab);
      app.Step3Label_2.Text = 'Step 3';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DataEntryV5

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end