classdef DataEntryV6 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                     matlab.ui.Figure
    TabGroup                     matlab.ui.container.TabGroup
    LoadNewDataTab               matlab.ui.container.Tab
    versionLabel                 matlab.ui.control.Label
    ORLabel                      matlab.ui.control.Label
    ContinueWorkingLabel         matlab.ui.control.Label
    StartfromScratchLabel        matlab.ui.control.Label
    NewActionDataButton          matlab.ui.control.Button
    LoadorcreatedatawilloverwritecurrentworkLabel  matlab.ui.control.Label
    ActionFilesListBox_1         matlab.ui.control.ListBox
    ActionFilesListBox_2Label    matlab.ui.control.Label
    LoadActionDataButton_1       matlab.ui.control.Button
    ListActionFilesButton_1      matlab.ui.control.Button
    AssignPlayersTab             matlab.ui.container.Tab
    FullRosterListBox            matlab.ui.control.ListBox
    TeamCListBoxLabel            matlab.ui.control.Label
    TeamDListBoxLabel            matlab.ui.control.Label
    AddtoTeamAButton             matlab.ui.control.Button
    AddtoTeamBButton             matlab.ui.control.Button
    AddtoTeamCButton             matlab.ui.control.Button
    AddtoTeamDButton             matlab.ui.control.Button
    TeamAListBox                 matlab.ui.control.ListBox
    TeamBListBox                 matlab.ui.control.ListBox
    TeamBListBoxLabel            matlab.ui.control.Label
    TeamAListBoxLabel            matlab.ui.control.Label
    FullRosterListBoxLabel       matlab.ui.control.Label
    TeamCListBox                 matlab.ui.control.ListBox
    TeamDListBox                 matlab.ui.control.ListBox
    DeleteLastButtonA            matlab.ui.control.Button
    DeleteLastButtonB            matlab.ui.control.Button
    DeleteLastButtonC            matlab.ui.control.Button
    DeleteLastButtonD            matlab.ui.control.Button
    AddActionsTab                matlab.ui.container.Tab
    Team2DropDown                matlab.ui.control.DropDown
    Team2DropDownLabel           matlab.ui.control.Label
    Team1DropDown                matlab.ui.control.DropDown
    Team1DropDownLabel           matlab.ui.control.Label
    EditActionsButton            matlab.ui.control.Button
    TeamNameLabel                matlab.ui.control.Label
    Team2ListBoxLabel            matlab.ui.control.Label
    Team1ListBoxLabel            matlab.ui.control.Label
    PlayerNameLabel              matlab.ui.control.Label
    DeleteLastEntryButton        matlab.ui.control.Button
    Image                        matlab.ui.control.Image
    StatusLampLabel              matlab.ui.control.Label
    Detail2ListBoxLabel          matlab.ui.control.Label
    Detail1ListBoxLabel          matlab.ui.control.Label
    NotesEditField               matlab.ui.control.EditField
    NotesEditFieldLabel          matlab.ui.control.Label
    ActionListBoxLabel           matlab.ui.control.Label
    StatusLamp                   matlab.ui.control.Lamp
    Detail2ListBox               matlab.ui.control.ListBox
    Detail1ListBox               matlab.ui.control.ListBox
    ActionListBox                matlab.ui.control.ListBox
    Team2ListBox                 matlab.ui.control.ListBox
    Team1ListBox                 matlab.ui.control.ListBox
    AddtoActionsButton           matlab.ui.control.Button
    SaveDataTab                  matlab.ui.container.Tab
    SaveactiondatatofileLabel    matlab.ui.control.Label
    DatafileNameEditFieldLabel   matlab.ui.control.Label
    DatafileNameEditField        matlab.ui.control.EditField
    muststartwithActionegAction0909Game2matLabel  matlab.ui.control.Label
    VariableNameGameActionsfixedLabel  matlab.ui.control.Label
    SaveDatatoFileButton         matlab.ui.control.Button
    GenerateStatsTab             matlab.ui.container.Tab
    Step2Label_3                 matlab.ui.control.Label
    LoadActionDataButton_2       matlab.ui.control.Button
    SaveactiondatabeforegeneratestatsLabel  matlab.ui.control.Label
    Step3Label                   matlab.ui.control.Label
    Step2Label                   matlab.ui.control.Label
    Step1Label                   matlab.ui.control.Label
    GenerateStatsButton          matlab.ui.control.Button
    StatsFilenameEditField       matlab.ui.control.EditField
    StatsFilenameEditFieldLabel  matlab.ui.control.Label
    ListActionFilesButton_2      matlab.ui.control.Button
    ActionFilesListBox_2         matlab.ui.control.ListBox
    ActionFilesListBoxLabel      matlab.ui.control.Label
    ShotMapTab                   matlab.ui.container.Tab
    Step3Label_2                 matlab.ui.control.Label
    GenerateShotMapButton        matlab.ui.control.Button
    PlayerListListBox            matlab.ui.control.ListBox
    PlayerListListBoxLabel       matlab.ui.control.Label
    Step2Label_2                 matlab.ui.control.Label
    LoadActionDataButton_3       matlab.ui.control.Button
    ShotmapforwholeteamoreachplayerLabel  matlab.ui.control.Label
    Step1Label_2                 matlab.ui.control.Label
    ListActionFilesButton_3      matlab.ui.control.Button
    ActionFilesListBox_3         matlab.ui.control.ListBox
    ActionFilesListBox_3Label    matlab.ui.control.Label
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: ListActionFilesButton_1, 
    % ListActionFilesButton_2, ListActionFilesButton_3
    function ListActionFiles(app, event)
      proj = matlab.project.currentProject;
      list = dir(strcat(proj.RootFolder,"/Data/Action*"));
      list = list(~cellfun('isempty', {list.date})); 
      filenames = {};
      for i = 1:length(list)
        tmplist = list(i);
        filenames{i} = tmplist.name;
      end
      app.ActionFilesListBox_2.Items = filenames;
      app.ActionFilesListBox_2.Value = app.ActionFilesListBox_2.Items(1);
      app.ActionFilesListBox_1.Items = filenames;
      app.ActionFilesListBox_1.Value = app.ActionFilesListBox_1.Items(1);      
      app.ActionFilesListBox_3.Items = filenames;
      app.ActionFilesListBox_3.Value = app.ActionFilesListBox_3.Items(1);      
    end

    % Button pushed function: LoadActionDataButton_1
    function loadActionData(app, event)
      load(app.ActionFilesListBox_1.Value)
      assignin('base','GameActions',GameActions);
    end

    % Button pushed function: NewActionDataButton
    function newActionData(app, event)
      init_GameActions;
      assignin('base','GameActions',GameActions);
    end

    % Button pushed function: AddtoTeamAButton
    function add2TeamA(app, event)
      n = length(app.TeamAListBox.Items);
      entry = app.FullRosterListBox.Value;
      m = length(entry);
      for i = 1:m
        app.TeamAListBox.Items{n+i-1} = entry{i};
      end
      app.TeamAListBox.Items{n+m} = ' ';
      app.TeamAListBox.Value = app.TeamAListBox.Items{n+m};
    end

    % Button pushed function: DeleteLastButtonA
    function Delete2TeamA(app, event)
      n = length(app.TeamAListBox.Items);
      if n > 1
        app.TeamAListBox.Items(n-1) = [];
      end
    end

    % Button pushed function: AddtoTeamBButton
    function add2TeamB(app, event)
      n = length(app.TeamBListBox.Items);
      entry = app.FullRosterListBox.Value;
      m = length(entry);
      for i = 1:m
        app.TeamBListBox.Items{n+i-1} = entry{i};
      end
      app.TeamBListBox.Items{n+m} = ' ';
      app.TeamBListBox.Value = app.TeamBListBox.Items{n+m};
    end

    % Button pushed function: DeleteLastButtonB
    function Delete2TeamB(app, event)
      n = length(app.TeamBListBox.Items);
      if n > 1
        app.TeamBListBox.Items(n-1) = [];
      end
    end

    % Value changed function: Team1DropDown
    function AssignTeam1(app, event)
      value = app.Team1DropDown.Value;
      switch value
        case "A"
          app.Team1ListBox.Items = app.TeamAListBox.Items;
        case "B"
          app.Team1ListBox.Items = app.TeamBListBox.Items;
        case "C"
          app.Team1ListBox.Items = app.TeamCListBox.Items;
        case "D"
          app.Team1ListBox.Items = app.TeamDListBox.Items;
        otherwise
          app.Team1ListBox.Items = {' '};                    
      end
      app.Team1ListBox.Value = ' ';
    end

    % Value changed function: Team2DropDown
    function AssignTeam2(app, event)
      value = app.Team2DropDown.Value;
      switch value
        case "A"
          app.Team2ListBox.Items = app.TeamAListBox.Items;
        case "B"
          app.Team2ListBox.Items = app.TeamBListBox.Items;
        case "C"
          app.Team2ListBox.Items = app.TeamCListBox.Items;
        case "D"
          app.Team2ListBox.Items = app.TeamDListBox.Items;
        otherwise
          app.Team2ListBox.Items = {' '};                    
      end
      app.Team2ListBox.Value = ' ';
    end

    % Value changed function: Team1ListBox
    function selectTeam1Player(app, event)
      value = app.Team1ListBox.Value;
      app.PlayerNameLabel.Text = value;
      TeamLabel = strcat("Team ",app.Team1DropDown.Value);
      app.TeamNameLabel.Text = TeamLabel;
      app.Team2ListBox.Value = ' ';
      try
        app.Image.ImageSource = strcat(value,".jpg");
      catch ME
      end
    end

    % Value changed function: Team2ListBox
    function selectTeam2Player(app, event)
      value = app.Team2ListBox.Value;
      app.PlayerNameLabel.Text = value;
      TeamLabel = strcat("Team ",app.Team2DropDown.Value);
      app.TeamNameLabel.Text = TeamLabel;
      app.Team1ListBox.Value = ' ';
      try
        app.Image.ImageSource = strcat(value,".jpg");
      catch ME
      end
    end

    % Value changed function: ActionListBox
    function selectAction(app, event)
      value = app.ActionListBox.Value;
      switch value
        case 'Pass'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Regular', 'Threat', 'Assist', 'Missed'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Make Shot'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Layup', 'Close',  '2 Left Elbow', '2 Left Corner', '2 Right Elbow', '2 Right Corner', '3 Top', '3 Left Wing', '3 Left Corner', '3 Right Wing', '3 Right Corner', 'FreeThrow'};
          app.Detail2ListBox.Items =  {'open', 'contested', ' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(3);
        case 'Miss Shot'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Layup', 'Close',  '2 Left Elbow', '2 Left Corner', '2 Right Elbow', '2 Right Corner', '3 Top', '3 Left Wing', '3 Left Corner', '3 Right Wing', '3 Right Corner', 'FreeThrow'};
          app.Detail2ListBox.Items =  {'open', 'contested', ' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(3);
        case 'Rebound'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Defensive', 'Offensive'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Defense'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Steal', 'Block', 'Shot Contest', 'Shot Allowed'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Turnover'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Pass', 'Travel', 'Dribble', 'Bound', 'Foul'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Other'
          app.Detail1ListBox.FontSize = 14;
          app.Detail1ListBox.Items =  {'Off-ball screen', 'Screen assist'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
      end
    end

    % Value changed function: Detail1ListBox
    function selectDetail1(app, event)
      value = app.Detail1ListBox.Value;
      if app.ActionListBox.Value == "Defense"
        switch value
          case "Shot Contest"
            app.Detail2ListBox.Items =  {'shot made', 'shot missed', ' '};
            app.Detail2ListBox.Value = app.Detail2ListBox.Items(3);
          case "Shot Allowed"
            app.Detail2ListBox.Items =  {'shot made', 'shot missed', ' '};
            app.Detail2ListBox.Value = app.Detail2ListBox.Items(3);
          otherwise
            app.Detail2ListBox.Items =  {' '};
            app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        end
      end
    end

    % Button pushed function: AddtoActionsButton
    function add2Variable(app, event)
      
      % get base workspace var into local
      GameActions = evalin('base','GameActions');
      idx = size(GameActions,1)+1;

      % arrange inputs from App
      entry = {};
      Team1Label = strcat("Team ",app.Team1DropDown.Value);
      Team2Label = strcat("Team ",app.Team2DropDown.Value);
      TeamLabel = char(app.TeamNameLabel.Text); % just keep "A" as in "Team A" for table entry
      TeamLabel = string(TeamLabel(end));  % convert back to string
      entry{1} = TeamLabel;
      entry{2} = app.PlayerNameLabel.Text;
      entry{3} = app.ActionListBox.Value;
      entry{4} = app.Detail1ListBox.Value;
      entry{5} = app.Detail2ListBox.Value;
      entry{6} = app.NotesEditField.Value;
      entry{7} = idx;      
      
      % add entry into database
      GameActions(idx,:) = entry;
      assignin('base','GameActions',GameActions);
      
      % reset some inputs fields after entering data
      app.NotesEditField.Value = "";
      
      % flash a light for visual
      for i = 1:3
        app.StatusLamp.Color = [0.8,0.8,0.8];
        pause(0.05)
        app.StatusLamp.Color = [0,0.6,1];
        pause(0.15)
      end
      app.StatusLamp.Color = [0,1,0];
      
      if app.ActionListBox.Value == "Miss Shot"
        app.ActionListBox.Value = 'Rebound';
        app.Detail1ListBox.FontSize = 14;
        app.Detail1ListBox.Items =  {'Defensive', 'Offensive'};
        app.Detail2ListBox.Items =  {' '};
        app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
        app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
      end

      if app.ActionListBox.Value == "Make Shot" | app.ActionListBox.Value == "Turnover" 
        app.ActionListBox.Value = 'Pass';
        app.Detail1ListBox.FontSize = 14;
        app.Detail1ListBox.Items =  {'Regular', 'Threat', 'Assist', 'Missed'};
        app.Detail2ListBox.Items =  {' '};
        app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
        app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        if app.TeamNameLabel.Text == Team1Label
          app.TeamNameLabel.Text = Team2Label;
          app.Team2ListBox.Value = app.Team2ListBox.Items(1);
          app.PlayerNameLabel.Text = app.Team2ListBox.Items(1);
          app.Team1ListBox.Value = ' ';
        else
          app.TeamNameLabel.Text = Team1Label;
          app.Team1ListBox.Value = app.Team1ListBox.Items(1);
          app.PlayerNameLabel.Text = app.Team1ListBox.Items(1);
          app.Team2ListBox.Value = ' ';
        end
      end
      
      if app.Detail1ListBox.Value == "Assist"
        app.ActionListBox.Value = 'Make Shot';
        app.Detail1ListBox.FontSize = 12;
        app.Detail1ListBox.Items =  {'Layup', 'Close',  '2 Left Elbow', '2 Left Corner', '2 Right Elbow', '2 Right Corner', '3 Top', '3 Left Wing', '3 Left Corner', '3 Right Wing', '3 Right Corner', 'FreeThrow'};
        app.Detail2ListBox.Items =  {' '};
        app.Detail1ListBox.Value = app.Detail1ListBox.Items(2);
        app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        app.Team1ListBox.Value = ' ';
        app.Team2ListBox.Value = ' ';
      end
      
    end

    % Button pushed function: DeleteLastEntryButton
    function DeleteLastEntry(app, event)
      % get base workspace var into local
      GameActions = evalin('base','GameActions');
      n = height(GameActions);
      if n > 1
        % delete last entry
        GameActions(end,:) = [];
        % update back into workspace
        assignin('base','GameActions',GameActions);
      end
      % flash a light for visual
      for i = 1:2
        app.StatusLamp.Color = [0.8,0.8,0.8];
        pause(0.05)
        app.StatusLamp.Color = [1,0,0];
        pause(0.15)
      end
      app.StatusLamp.Color = [0,1,0];
    end

    % Button pushed function: EditActionsButton
    function OpenGameActions(app, event)
      openvar('GameActions')
    end

    % Button pushed function: SaveDatatoFileButton
    function save2File(app, event)
      proj = matlab.project.currentProject;
      GameActions = evalin('base','GameActions');    % get base workspace var into local
      save(strcat(proj.RootFolder,"/Data/",app.DatafileNameEditField.Value),"GameActions")
    end

    % Button pushed function: LoadActionDataButton_2
    function loadData2Stats(app, event)
      filelist = app.ActionFilesListBox_2.Value;
      n = length(filelist);
      load(filelist{1});
      tmpActions = GameActions;
      for i = 2:n
        load(filelist{i})
        tmpActions = [tmpActions; GameActions];  %#ok<AGROW>
      end
      GameActions = tmpActions;
      assignin('base','GameActions',GameActions);
    end

    % Button pushed function: GenerateStatsButton
    function generateStats(app, event)
      GameActions = evalin('base','GameActions');    % get base workspace var into local
      outfile  = app.StatsFilenameEditField.Value;
      func_GenerateStats(GameActions,outfile);
    end

    % Button pushed function: LoadActionDataButton_3
    function loadData2Map(app, event)
      filelist = app.ActionFilesListBox_3.Value;
      n = length(filelist);
      load(filelist{1});
      tmpActions = GameActions;
      for i = 2:n
        load(filelist{i})
        tmpActions = [tmpActions; GameActions];  %#ok<AGROW>
      end
      GameActions = tmpActions;
      assignin('base','GameActions',GameActions);
      varPlayer = unique(GameActions.Player);
      app.PlayerListListBox.Items = ["All"; varPlayer];
      app.PlayerListListBox.Value = app.PlayerListListBox.Items(1);
    end

    % Button pushed function: GenerateShotMapButton
    function generateShotMap(app, event)
      Players = app.PlayerListListBox.Value;
      GameActions = evalin('base','GameActions');    % get base workspace var into local
      outfile  = app.StatsFilenameEditField.Value;
      func_GenerateShotMap(GameActions,Players,outfile);
    end

    % Button pushed function: DeleteLastButtonC
    function Delete2TeamC(app, event)
      n = length(app.TeamCListBox.Items);
      if n > 1
        app.TeamCListBox.Items(n-1) = [];
      end
    end

    % Button pushed function: DeleteLastButtonD
    function Delete2TeamD(app, event)
      n = length(app.TeamDListBox.Items);
      if n > 1
        app.TeamDListBox.Items(n-1) = [];
      end
    end

    % Button pushed function: AddtoTeamCButton
    function add2TeamC(app, event)
      n = length(app.TeamCListBox.Items);
      entry = app.FullRosterListBox.Value;
      m = length(entry);
      for i = 1:m
        app.TeamCListBox.Items{n+i-1} = entry{i};
      end
      app.TeamCListBox.Items{n+m} = ' ';
      app.TeamCListBox.Value = app.TeamCListBox.Items{n+m};
    end

    % Button pushed function: AddtoTeamDButton
    function add2TeamD(app, event)
      n = length(app.TeamDListBox.Items);
      entry = app.FullRosterListBox.Value;
      m = length(entry);
      for i = 1:m
        app.TeamDListBox.Items{n+i-1} = entry{i};
      end
      app.TeamDListBox.Items{n+m} = ' ';
      app.TeamDListBox.Value = app.TeamDListBox.Items{n+m};
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.TabGroup = uitabgroup(app.UIFigure);
      app.LoadNewDataTab = uitab(app.TabGroup);
      app.LoadNewDataTab.Title = 'Load/New Data';
      app.ListActionFilesButton_1 = uibutton(app.LoadNewDataTab, 'push');
      app.ListActionFilesButton_1.ButtonPushedFcn = createCallbackFcn(app, @ListActionFiles, true);
      app.ListActionFilesButton_1.Text = 'List Action Files';
      app.LoadActionDataButton_1 = uibutton(app.LoadNewDataTab, 'push');
      app.LoadActionDataButton_1.ButtonPushedFcn = createCallbackFcn(app, @loadActionData, true);
      app.LoadActionDataButton_1.Text = 'Load Action Data';
      app.ActionFilesListBox_2Label = uilabel(app.LoadNewDataTab);
      app.ActionFilesListBox_2Label.Text = 'Action Files';
      app.ActionFilesListBox_1 = uilistbox(app.LoadNewDataTab);
      app.ActionFilesListBox_1.Items = {};
      app.ActionFilesListBox_1.Value = {};
      app.LoadorcreatedatawilloverwritecurrentworkLabel = uilabel(app.LoadNewDataTab);
      app.LoadorcreatedatawilloverwritecurrentworkLabel.Text = 'Load or create data will overwrite current work!!!';
      app.NewActionDataButton = uibutton(app.LoadNewDataTab, 'push');
      app.NewActionDataButton.ButtonPushedFcn = createCallbackFcn(app, @newActionData, true);
      app.NewActionDataButton.Text = 'New Action Data';
      app.StartfromScratchLabel = uilabel(app.LoadNewDataTab);
      app.StartfromScratchLabel.Text = 'Start from Scratch';
      app.ContinueWorkingLabel = uilabel(app.LoadNewDataTab);
      app.ContinueWorkingLabel.Text = 'Continue Working';
      app.ORLabel = uilabel(app.LoadNewDataTab);
      app.ORLabel.Text = 'OR';
      app.versionLabel = uilabel(app.LoadNewDataTab);
      app.versionLabel.Text = 'v6';
      app.AssignPlayersTab = uitab(app.TabGroup);
      app.AssignPlayersTab.Title = 'Assign Players';
      app.DeleteLastButtonD = uibutton(app.AssignPlayersTab, 'push');
      app.DeleteLastButtonD.ButtonPushedFcn = createCallbackFcn(app, @Delete2TeamD, true);
      app.DeleteLastButtonD.Text = 'Delete Last';
      app.DeleteLastButtonC = uibutton(app.AssignPlayersTab, 'push');
      app.DeleteLastButtonC.ButtonPushedFcn = createCallbackFcn(app, @Delete2TeamC, true);
      app.DeleteLastButtonC.Text = 'Delete Last';
      app.DeleteLastButtonB = uibutton(app.AssignPlayersTab, 'push');
      app.DeleteLastButtonB.ButtonPushedFcn = createCallbackFcn(app, @Delete2TeamB, true);
      app.DeleteLastButtonB.Text = 'Delete Last';
      app.DeleteLastButtonA = uibutton(app.AssignPlayersTab, 'push');
      app.DeleteLastButtonA.ButtonPushedFcn = createCallbackFcn(app, @Delete2TeamA, true);
      app.DeleteLastButtonA.Text = 'Delete Last';
      app.TeamDListBox = uilistbox(app.AssignPlayersTab);
      app.TeamDListBox.Items = {' '};
      app.TeamDListBox.Value = ' ';
      app.TeamCListBox = uilistbox(app.AssignPlayersTab);
      app.TeamCListBox.Items = {' '};
      app.TeamCListBox.Value = ' ';
      app.FullRosterListBoxLabel = uilabel(app.AssignPlayersTab);
      app.FullRosterListBoxLabel.Text = 'Full Roster';
      app.TeamAListBoxLabel = uilabel(app.AssignPlayersTab);
      app.TeamAListBoxLabel.Text = 'Team A';
      app.TeamBListBoxLabel = uilabel(app.AssignPlayersTab);
      app.TeamBListBoxLabel.Text = 'Team B';
      app.TeamBListBox = uilistbox(app.AssignPlayersTab);
      app.TeamBListBox.Items = {' '};
      app.TeamBListBox.Value = ' ';
      app.TeamAListBox = uilistbox(app.AssignPlayersTab);
      app.TeamAListBox.Items = {' '};
      app.TeamAListBox.Value = ' ';
      app.AddtoTeamDButton = uibutton(app.AssignPlayersTab, 'push');
      app.AddtoTeamDButton.ButtonPushedFcn = createCallbackFcn(app, @add2TeamD, true);
      app.AddtoTeamDButton.Text = 'Add to Team D';
      app.AddtoTeamCButton = uibutton(app.AssignPlayersTab, 'push');
      app.AddtoTeamCButton.ButtonPushedFcn = createCallbackFcn(app, @add2TeamC, true);
      app.AddtoTeamCButton.Text = 'Add to Team C';
      app.AddtoTeamBButton = uibutton(app.AssignPlayersTab, 'push');
      app.AddtoTeamBButton.ButtonPushedFcn = createCallbackFcn(app, @add2TeamB, true);
      app.AddtoTeamBButton.Text = 'Add to Team B';
      app.AddtoTeamAButton = uibutton(app.AssignPlayersTab, 'push');
      app.AddtoTeamAButton.ButtonPushedFcn = createCallbackFcn(app, @add2TeamA, true);
      app.AddtoTeamAButton.Text = 'Add to Team A';
      app.TeamDListBoxLabel = uilabel(app.AssignPlayersTab);
      app.TeamDListBoxLabel.Text = 'Team D';
      app.TeamCListBoxLabel = uilabel(app.AssignPlayersTab);
      app.TeamCListBoxLabel.Text = 'Team C';
      app.FullRosterListBox = uilistbox(app.AssignPlayersTab);
      app.FullRosterListBox.Items = {'Alex_YXY', 'Andrew_Du', 'Archie_ZYQ', 'BK_FYP', 'Bob_BKZ', 'Bobo_PB', 'Chione_XCY', 'CUBA_LCY', 'Daniel_XZY', 'David_HQC', 'Hou_Hanfu', 'Jam_LTJ', 'JimBig_ZYJ', 'JimLit_XQH', 'Kevin_MZQ', 'Ma_Liyan', 'Michael_CBC', 'Neo_LWQ', 'Nicole_LXR', 'Paras_WXH', 'Tang_Yifeng', 'Tony_ZBR', 'Tyzer_TZX', 'Wan_Yuxin', 'Will_ZJW', 'Yuan_Xincheng'};
      app.FullRosterListBox.Multiselect = 'on';
      app.FullRosterListBox.Value = {'Alex_YXY'};
      app.AddActionsTab = uitab(app.TabGroup);
      app.AddActionsTab.Title = 'Add Actions';
      app.AddtoActionsButton = uibutton(app.AddActionsTab, 'push');
      app.AddtoActionsButton.ButtonPushedFcn = createCallbackFcn(app, @add2Variable, true);
      app.AddtoActionsButton.Text = 'Add to Actions';
      app.Team1ListBox = uilistbox(app.AddActionsTab);
      app.Team1ListBox.Items = {};
      app.Team1ListBox.ValueChangedFcn = createCallbackFcn(app, @selectTeam1Player, true);
      app.Team1ListBox.Value = {};
      app.Team2ListBox = uilistbox(app.AddActionsTab);
      app.Team2ListBox.Items = {};
      app.Team2ListBox.ValueChangedFcn = createCallbackFcn(app, @selectTeam2Player, true);
      app.Team2ListBox.Value = {};
      app.ActionListBox = uilistbox(app.AddActionsTab);
      app.ActionListBox.Items = {'Pass', 'Make Shot', 'Miss Shot', 'Rebound', 'Turnover', 'Defense', 'Other'};
      app.ActionListBox.ValueChangedFcn = createCallbackFcn(app, @selectAction, true);
      app.ActionListBox.Value = 'Pass';
      app.Detail1ListBox = uilistbox(app.AddActionsTab);
      app.Detail1ListBox.Items = {'Regular', 'Threat', 'Assist', 'Missed'};
      app.Detail1ListBox.ValueChangedFcn = createCallbackFcn(app, @selectDetail1, true);
      app.Detail1ListBox.Value = 'Regular';
      app.Detail2ListBox = uilistbox(app.AddActionsTab);
      app.Detail2ListBox.Items = {' '};
      app.Detail2ListBox.Value = ' ';
      app.StatusLamp = uilamp(app.AddActionsTab);
      app.ActionListBoxLabel = uilabel(app.AddActionsTab);
      app.ActionListBoxLabel.Text = 'Action';
      app.NotesEditFieldLabel = uilabel(app.AddActionsTab);
      app.NotesEditFieldLabel.Text = 'Notes';
      app.NotesEditField = uieditfield(app.AddActionsTab, 'text');
      app.Detail1ListBoxLabel = uilabel(app.AddActionsTab);
      app.Detail1ListBoxLabel.Text = 'Detail1';
      app.Detail2ListBoxLabel = uilabel(app.AddActionsTab);
      app.Detail2ListBoxLabel.Text = 'Detail2';
      app.StatusLampLabel = uilabel(app.AddActionsTab);
      app.StatusLampLabel.Text = 'Status';
      app.Image = uiimage(app.AddActionsTab);
      app.DeleteLastEntryButton = uibutton(app.AddActionsTab, 'push');
      app.DeleteLastEntryButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastEntry, true);
      app.DeleteLastEntryButton.Text = {'Delete '; 'Last Entry'};
      app.PlayerNameLabel = uilabel(app.AddActionsTab);
      app.PlayerNameLabel.Text = 'Player Name';
      app.Team1ListBoxLabel = uilabel(app.AddActionsTab);
      app.Team1ListBoxLabel.Text = 'Team 1';
      app.Team2ListBoxLabel = uilabel(app.AddActionsTab);
      app.Team2ListBoxLabel.Text = 'Team 2';
      app.TeamNameLabel = uilabel(app.AddActionsTab);
      app.TeamNameLabel.Text = 'Team Name';
      app.EditActionsButton = uibutton(app.AddActionsTab, 'push');
      app.EditActionsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGameActions, true);
      app.EditActionsButton.Text = {'Edit '; 'Actions'};
      app.Team1DropDownLabel = uilabel(app.AddActionsTab);
      app.Team1DropDownLabel.Text = 'Team 1';
      app.Team1DropDown = uidropdown(app.AddActionsTab);
      app.Team1DropDown.Items = {'N/A', 'A', 'B', 'C', 'D'};
      app.Team1DropDown.ValueChangedFcn = createCallbackFcn(app, @AssignTeam1, true);
      app.Team1DropDown.Value = 'N/A';
      app.Team2DropDownLabel = uilabel(app.AddActionsTab);
      app.Team2DropDownLabel.Text = 'Team 2';
      app.Team2DropDown = uidropdown(app.AddActionsTab);
      app.Team2DropDown.Items = {'N/A', 'A', 'B', 'C', 'D'};
      app.Team2DropDown.ValueChangedFcn = createCallbackFcn(app, @AssignTeam2, true);
      app.Team2DropDown.Value = 'N/A';
      app.SaveDataTab = uitab(app.TabGroup);
      app.SaveDataTab.Title = 'Save Data';
      app.SaveDatatoFileButton = uibutton(app.SaveDataTab, 'push');
      app.SaveDatatoFileButton.ButtonPushedFcn = createCallbackFcn(app, @save2File, true);
      app.SaveDatatoFileButton.Text = 'Save Data to File';
      app.VariableNameGameActionsfixedLabel = uilabel(app.SaveDataTab);
      app.VariableNameGameActionsfixedLabel.Text = 'Variable Name: GameActions (fixed)';
      app.muststartwithActionegAction0909Game2matLabel = uilabel(app.SaveDataTab);
      app.muststartwithActionegAction0909Game2matLabel.Text = ' must start with "Action", e.g. Action0909Game2.mat';
      app.DatafileNameEditField = uieditfield(app.SaveDataTab, 'text');
      app.DatafileNameEditField.Value = 'ActionDateContent.mat';
      app.DatafileNameEditFieldLabel = uilabel(app.SaveDataTab);
      app.DatafileNameEditFieldLabel.Text = 'Datafile Name:';
      app.SaveactiondatatofileLabel = uilabel(app.SaveDataTab);
      app.SaveactiondatatofileLabel.Text = 'Save action data to file';
      app.GenerateStatsTab = uitab(app.TabGroup);
      app.GenerateStatsTab.Title = 'Generate Stats';
      app.ActionFilesListBoxLabel = uilabel(app.GenerateStatsTab);
      app.ActionFilesListBoxLabel.Text = 'Action Files';
      app.ActionFilesListBox_2 = uilistbox(app.GenerateStatsTab);
      app.ActionFilesListBox_2.Items = {};
      app.ActionFilesListBox_2.Multiselect = 'on';
      app.ActionFilesListBox_2.Value = {};
      app.ListActionFilesButton_2 = uibutton(app.GenerateStatsTab, 'push');
      app.ListActionFilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ListActionFiles, true);
      app.ListActionFilesButton_2.Text = 'List Action Files';
      app.StatsFilenameEditFieldLabel = uilabel(app.GenerateStatsTab);
      app.StatsFilenameEditFieldLabel.Text = 'Stats Filename';
      app.StatsFilenameEditField = uieditfield(app.GenerateStatsTab, 'text');
      app.StatsFilenameEditField.Value = 'StatsTest';
      app.GenerateStatsButton = uibutton(app.GenerateStatsTab, 'push');
      app.GenerateStatsButton.ButtonPushedFcn = createCallbackFcn(app, @generateStats, true);
      app.GenerateStatsButton.Text = 'Generate Stats';
      app.Step1Label = uilabel(app.GenerateStatsTab);
      app.Step1Label.Text = 'Step 1';
      app.Step2Label = uilabel(app.GenerateStatsTab);
      app.Step2Label.Text = 'Step 2';
      app.Step3Label = uilabel(app.GenerateStatsTab);
      app.Step3Label.Text = 'Step 3';
      app.SaveactiondatabeforegeneratestatsLabel = uilabel(app.GenerateStatsTab);
      app.SaveactiondatabeforegeneratestatsLabel.Text = 'Save action data before generate stats!!!';
      app.LoadActionDataButton_2 = uibutton(app.GenerateStatsTab, 'push');
      app.LoadActionDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @loadData2Stats, true);
      app.LoadActionDataButton_2.Text = 'Load Action Data';
      app.Step2Label_3 = uilabel(app.GenerateStatsTab);
      app.Step2Label_3.Text = 'Step 2';
      app.ShotMapTab = uitab(app.TabGroup);
      app.ShotMapTab.Title = 'Shot Map';
      app.ActionFilesListBox_3Label = uilabel(app.ShotMapTab);
      app.ActionFilesListBox_3Label.Text = 'Action Files';
      app.ActionFilesListBox_3 = uilistbox(app.ShotMapTab);
      app.ActionFilesListBox_3.Items = {};
      app.ActionFilesListBox_3.Multiselect = 'on';
      app.ActionFilesListBox_3.Value = {};
      app.ListActionFilesButton_3 = uibutton(app.ShotMapTab, 'push');
      app.ListActionFilesButton_3.ButtonPushedFcn = createCallbackFcn(app, @ListActionFiles, true);
      app.ListActionFilesButton_3.Text = 'List Action Files';
      app.Step1Label_2 = uilabel(app.ShotMapTab);
      app.Step1Label_2.Text = 'Step 1';
      app.ShotmapforwholeteamoreachplayerLabel = uilabel(app.ShotMapTab);
      app.ShotmapforwholeteamoreachplayerLabel.Text = 'Shot map for whole team or each player';
      app.LoadActionDataButton_3 = uibutton(app.ShotMapTab, 'push');
      app.LoadActionDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @loadData2Map, true);
      app.LoadActionDataButton_3.Text = 'Load Action Data';
      app.Step2Label_2 = uilabel(app.ShotMapTab);
      app.Step2Label_2.Text = 'Step 2';
      app.PlayerListListBoxLabel = uilabel(app.ShotMapTab);
      app.PlayerListListBoxLabel.Text = 'Player List';
      app.PlayerListListBox = uilistbox(app.ShotMapTab);
      app.PlayerListListBox.Items = {' '};
      app.PlayerListListBox.Multiselect = 'on';
      app.PlayerListListBox.Value = {' '};
      app.GenerateShotMapButton = uibutton(app.ShotMapTab, 'push');
      app.GenerateShotMapButton.ButtonPushedFcn = createCallbackFcn(app, @generateShotMap, true);
      app.GenerateShotMapButton.Text = 'Generate Shot Map';
      app.Step3Label_2 = uilabel(app.ShotMapTab);
      app.Step3Label_2.Text = 'Step 3';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DataEntryV6

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end