classdef POC_DataEntry < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    VariableNameGameActionsLabel  matlab.ui.control.Label
    DatafileNameEditFieldLabel    matlab.ui.control.Label
    DatafileNameEditField         matlab.ui.control.EditField
    SaveVariabletoFileButton      matlab.ui.control.Button
    AddtoVariableButton           matlab.ui.control.Button
    ResultListBox                 matlab.ui.control.ListBox
    ResultListBoxLabel            matlab.ui.control.Label
    ActionListBox                 matlab.ui.control.ListBox
    ActionListBoxLabel            matlab.ui.control.Label
    Player1ListBox                matlab.ui.control.ListBox
    Player1ListBoxLabel           matlab.ui.control.Label
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: AddtoVariableButton
    function add2Variable(app, event)
      GameActions = evalin('base','GameActions');            % get base workspace var into local
      idx = size(GameActions,1)+1;
      entry = {app.ActionListBox.Value, app.Player1ListBox.Value, app.ResultListBox.Value};
      entry{3} = str2double(entry{3});
      GameActions(idx,:) = entry;
      assignin('base','GameActions',GameActions);
    end

    % Button pushed function: SaveVariabletoFileButton
    function save2File(app, event)
      GameActions = evalin('base','GameActions');            % get base workspace var into local
      filename = ['./Data/' app.DatafileNameEditField.Value];
      save(filename,"GameActions")
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.Player1ListBoxLabel = uilabel(app.UIFigure);
      app.Player1ListBoxLabel.Text = 'Player1';
      app.Player1ListBox = uilistbox(app.UIFigure);
      app.Player1ListBox.Items = {'Adam', 'Ben', 'Cole ', 'Dan ', 'Eddy ', 'Frank', 'Gary', 'Henry'};
      app.Player1ListBox.Value = 'Adam';
      app.ActionListBoxLabel = uilabel(app.UIFigure);
      app.ActionListBoxLabel.Text = 'Action';
      app.ActionListBox = uilistbox(app.UIFigure);
      app.ActionListBox.Items = {'Pass', '2pt', '3pt', 'Layup', 'FT'};
      app.ActionListBox.Value = 'Pass';
      app.ResultListBoxLabel = uilabel(app.UIFigure);
      app.ResultListBoxLabel.Text = 'Result';
      app.ResultListBox = uilistbox(app.UIFigure);
      app.ResultListBox.Items = {'0', '1'};
      app.ResultListBox.ItemsData = {'0', '1'};
      app.ResultListBox.Value = '0';
      app.AddtoVariableButton = uibutton(app.UIFigure, 'push');
      app.AddtoVariableButton.ButtonPushedFcn = createCallbackFcn(app, @add2Variable, true);
      app.AddtoVariableButton.Text = 'Add to Variable';
      app.SaveVariabletoFileButton = uibutton(app.UIFigure, 'push');
      app.SaveVariabletoFileButton.ButtonPushedFcn = createCallbackFcn(app, @save2File, true);
      app.SaveVariabletoFileButton.Text = 'Save Variable to File';
      app.DatafileNameEditField = uieditfield(app.UIFigure, 'text');
      app.DatafileNameEditField.Value = 'POC_ActionTable.mat';
      app.DatafileNameEditFieldLabel = uilabel(app.UIFigure);
      app.DatafileNameEditFieldLabel.Text = 'Datafile Name:';
      app.VariableNameGameActionsLabel = uilabel(app.UIFigure);
      app.VariableNameGameActionsLabel.Text = ' Variable Name:  GameActions';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = POC_DataEntry

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end