classdef DataEntryV1 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    TeamListBox                   matlab.ui.control.ListBox
    TeamListBoxLabel              matlab.ui.control.Label
    NotesEditField                matlab.ui.control.EditField
    NotesEditFieldLabel           matlab.ui.control.Label
    OtherListBox                  matlab.ui.control.ListBox
    OtherListBoxLabel             matlab.ui.control.Label
    DefenseListBox                matlab.ui.control.ListBox
    DefenseListBoxLabel           matlab.ui.control.Label
    ResultListBox                 matlab.ui.control.ListBox
    ResultListBoxLabel            matlab.ui.control.Label
    PassListBox                   matlab.ui.control.ListBox
    PassListBoxLabel              matlab.ui.control.Label
    ReboundListBox                matlab.ui.control.ListBox
    ReboundListBoxLabel           matlab.ui.control.Label
    ShootListBox                  matlab.ui.control.ListBox
    ShootListBoxLabel             matlab.ui.control.Label
    VariableNameGameActionsLabel  matlab.ui.control.Label
    DatafileNameEditFieldLabel    matlab.ui.control.Label
    DatafileNameEditField         matlab.ui.control.EditField
    SaveVariabletoFileButton      matlab.ui.control.Button
    AddtoActionsButton            matlab.ui.control.Button
    ActionListBox                 matlab.ui.control.ListBox
    ActionListBoxLabel            matlab.ui.control.Label
    PlayerListBox                 matlab.ui.control.ListBox
    PlayerListBoxLabel            matlab.ui.control.Label
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: AddtoActionsButton
    function add2Variable(app, event)
      % get base workspace var into local
      GameActions = evalin('base','GameActions');
      idx = size(GameActions,1)+1;
      % arrange inputs from App
      entry = {};
      entry{1} = app.TeamListBox.Value;
      entry{2} = app.PlayerListBox.Value;
      entry{3} = app.ActionListBox.Value;
      entry{4} = app.ShootListBox.Value;
      entry{5} = app.ResultListBox.Value;
      entry{6} = app.PassListBox.Value;
      entry{7} = app.ReboundListBox.Value;
      entry{8} = app.DefenseListBox.Value;
      entry{9} = app.OtherListBox.Value;
      entry{10}= app.NotesEditField.Value;
      % add entry into database
      GameActions(idx,:) = entry;
      assignin('base','GameActions',GameActions);
      % reset inputs fields after entering data
      % player, action field won't reset
      app.TeamListBox.Value = app.TeamListBox.Items(1);        
      app.NotesEditField.Value = "";
      app.OtherListBox.Value = app.OtherListBox.Items(1);        
      app.DefenseListBox.Value = app.DefenseListBox.Items(1);        
      app.ResultListBox.Value = app.ResultListBox.Items(1);        
      app.ReboundListBox.Value = app.ReboundListBox.Items(1);        
      app.PassListBox.Value = app.PassListBox.Items(1);        
      app.ShootListBox.Value = app.ShootListBox.Items(1);
    end

    % Button pushed function: SaveVariabletoFileButton
    function save2File(app, event)
      GameActions = evalin('base','GameActions');            % get base workspace var into local
      filename = ['./Data/' app.DatafileNameEditField.Value];
      save(filename,"GameActions")
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.PlayerListBoxLabel = uilabel(app.UIFigure);
      app.PlayerListBoxLabel.Text = 'Player';
      app.PlayerListBox = uilistbox(app.UIFigure);
      app.PlayerListBox.Items = {'Alex_YXY ', 'Andrew_Du ', 'Archie_ZYQ ', 'BK_FYP ', 'Bob_BKZ ', 'Bobo_PB ', 'Daniel_XZY ', 'David_HQC ', 'Hou_HF ', 'Jam_LTJ ', 'Jim_ZYJ ', 'Kevin_MZQ ', 'Michael_CBC ', 'Neo_LWQ ', 'Paras_WXH ', 'Tyzer_TZX ', 'Wan_Yuxin ', 'Will_ZJW ', 'Yuan_XC ', 'CUBA_LCY ', 'Jim_XQH ', 'Chione_XCY ', 'Nicole_LXR ', 'Tang_YF ', 'Ma_LY ', 'Ken_BYX ', 'Tony_ZBR ', 'Allen_GHH ', 'Zhu_XY ', 'Bobby_ZCB '};
      app.PlayerListBox.Value = 'Alex_YXY ';
      app.ActionListBoxLabel = uilabel(app.UIFigure);
      app.ActionListBoxLabel.Text = 'Action';
      app.ActionListBox = uilistbox(app.UIFigure);
      app.ActionListBox.Items = {'Pass', 'Shoot', 'Rebound', 'Turnover', 'Defense', 'Other'};
      app.ActionListBox.Value = 'Pass';
      app.AddtoActionsButton = uibutton(app.UIFigure, 'push');
      app.AddtoActionsButton.ButtonPushedFcn = createCallbackFcn(app, @add2Variable, true);
      app.AddtoActionsButton.Text = 'Add to Actions';
      app.SaveVariabletoFileButton = uibutton(app.UIFigure, 'push');
      app.SaveVariabletoFileButton.ButtonPushedFcn = createCallbackFcn(app, @save2File, true);
      app.SaveVariabletoFileButton.Text = 'Save Variable to File';
      app.DatafileNameEditField = uieditfield(app.UIFigure, 'text');
      app.DatafileNameEditField.Value = 'ActionTableTest.mat';
      app.DatafileNameEditFieldLabel = uilabel(app.UIFigure);
      app.DatafileNameEditFieldLabel.Text = 'Datafile Name:';
      app.VariableNameGameActionsLabel = uilabel(app.UIFigure);
      app.VariableNameGameActionsLabel.Text = ' Variable Name:  GameActions';
      app.ShootListBoxLabel = uilabel(app.UIFigure);
      app.ShootListBoxLabel.Text = ' Shoot';
      app.ShootListBox = uilistbox(app.UIFigure);
      app.ShootListBox.Items = {'N/A', 'FreeThrow', 'Layup', 'Close', '2ShortL', '2ShortTop', '2ShortR', '2LongLC', '2LongLW', '2LongTop', '2LongRW', '2LongRC', '3LC', '3LW', '3Top', '3RW', '3RC', ''};
      app.ShootListBox.Value = 'N/A';
      app.ReboundListBoxLabel = uilabel(app.UIFigure);
      app.ReboundListBoxLabel.Text = 'Rebound';
      app.ReboundListBox = uilistbox(app.UIFigure);
      app.ReboundListBox.Items = {'N/A', 'Offensive', 'Defensive'};
      app.ReboundListBox.Value = 'N/A';
      app.PassListBoxLabel = uilabel(app.UIFigure);
      app.PassListBoxLabel.Text = 'Pass';
      app.PassListBox = uilistbox(app.UIFigure);
      app.PassListBox.Items = {'N/A', 'Regular', 'Threat', 'Assist', 'Turnover'};
      app.PassListBox.Value = 'N/A';
      app.ResultListBoxLabel = uilabel(app.UIFigure);
      app.ResultListBoxLabel.Text = 'Result';
      app.ResultListBox = uilistbox(app.UIFigure);
      app.ResultListBox.Items = {'N/A', 'miss', 'made'};
      app.ResultListBox.Value = 'N/A';
      app.DefenseListBoxLabel = uilabel(app.UIFigure);
      app.DefenseListBoxLabel.Text = 'Defense';
      app.DefenseListBox = uilistbox(app.UIFigure);
      app.DefenseListBox.Items = {'N/A', 'Steal', 'Block', 'Shot Contest', 'Shot Allowed'};
      app.DefenseListBox.Value = 'N/A';
      app.OtherListBoxLabel = uilabel(app.UIFigure);
      app.OtherListBoxLabel.Text = 'Other';
      app.OtherListBox = uilistbox(app.UIFigure);
      app.OtherListBox.Items = {'N/A', 'Off-ball screen', 'Screen assist'};
      app.OtherListBox.Value = 'N/A';
      app.NotesEditFieldLabel = uilabel(app.UIFigure);
      app.NotesEditFieldLabel.Text = 'Notes';
      app.NotesEditField = uieditfield(app.UIFigure, 'text');
      app.TeamListBoxLabel = uilabel(app.UIFigure);
      app.TeamListBoxLabel.Text = 'Team';
      app.TeamListBox = uilistbox(app.UIFigure);
      app.TeamListBox.Items = {'N/A', 'A', 'B', 'C', 'D'};
      app.TeamListBox.Value = 'N/A';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DataEntryV1

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end