classdef DataEntryV4 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                     matlab.ui.Figure
    TabGroup                     matlab.ui.container.TabGroup
    LoadNewDataTab               matlab.ui.container.Tab
    ORLabel                      matlab.ui.control.Label
    ContinueWorkingLabel         matlab.ui.control.Label
    StartfromScratchLabel        matlab.ui.control.Label
    NewActionDataButton          matlab.ui.control.Button
    LoadorcreatenewdatawilloverwritecurrentworkLabel  matlab.ui.control.Label
    ActionFilesListBox_2         matlab.ui.control.ListBox
    ActionFilesListBox_2Label    matlab.ui.control.Label
    LoadActionDataButton         matlab.ui.control.Button
    ListActionFilesButton_2      matlab.ui.control.Button
    AssignPlayersTab             matlab.ui.container.Tab
    AddtoTeamBButton             matlab.ui.control.Button
    AddtoTeamAButton             matlab.ui.control.Button
    DeleteLastButtonB            matlab.ui.control.Button
    DeleteLastButtonA            matlab.ui.control.Button
    TeamBListBox                 matlab.ui.control.ListBox
    TeamBListBoxLabel            matlab.ui.control.Label
    TeamAListBox                 matlab.ui.control.ListBox
    TeamAListBoxLabel            matlab.ui.control.Label
    FullRosterListBox            matlab.ui.control.ListBox
    FullRosterListBoxLabel       matlab.ui.control.Label
    AddActionsTab                matlab.ui.container.Tab
    TeamListBox                  matlab.ui.control.ListBox
    TeamListBoxLabel             matlab.ui.control.Label
    PlayerListBox                matlab.ui.control.ListBox
    PlayerListBoxLabel           matlab.ui.control.Label
    DeleteLastEntryButton        matlab.ui.control.Button
    StatusLamp                   matlab.ui.control.Lamp
    StatusLampLabel              matlab.ui.control.Label
    Detail2ListBox               matlab.ui.control.ListBox
    Detail2ListBoxLabel          matlab.ui.control.Label
    Detail1ListBox               matlab.ui.control.ListBox
    Detail1ListBoxLabel          matlab.ui.control.Label
    NotesEditField               matlab.ui.control.EditField
    NotesEditFieldLabel          matlab.ui.control.Label
    ActionListBox                matlab.ui.control.ListBox
    ActionListBoxLabel           matlab.ui.control.Label
    AddtoActionsButton           matlab.ui.control.Button
    SaveDataTab                  matlab.ui.container.Tab
    DatafileNameEditFieldLabel   matlab.ui.control.Label
    DatafileNameEditField        matlab.ui.control.EditField
    startwithActionegAction0909Game2matLabel  matlab.ui.control.Label
    VariableNameGameActionsfixedLabel  matlab.ui.control.Label
    SaveDatatoFileButton         matlab.ui.control.Button
    GenerateStatsTab             matlab.ui.container.Tab
    SaveactiondatabeforegeneratestatsLabel  matlab.ui.control.Label
    Step3Label                   matlab.ui.control.Label
    Step2Label                   matlab.ui.control.Label
    Step1Label                   matlab.ui.control.Label
    GenerateStatsButton          matlab.ui.control.Button
    StatsFilenameEditField       matlab.ui.control.EditField
    StatsFilenameEditFieldLabel  matlab.ui.control.Label
    ListActionFilesButton        matlab.ui.control.Button
    ActionFilesListBox           matlab.ui.control.ListBox
    ActionFilesListBoxLabel      matlab.ui.control.Label
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: ListActionFilesButton, 
    % ListActionFilesButton_2
    function ListActionFiles(app, event)
      proj = matlab.project.currentProject;
      list = dir(strcat(proj.RootFolder,"\Data\Action*"));
      filenames = {};
      for i = 1:length(list)
        filenames{i} = list(i).name;
      end
      app.ActionFilesListBox.Items = filenames;
      app.ActionFilesListBox_2.Items = filenames;
    end

    % Button pushed function: LoadActionDataButton
    function loadActionData(app, event)
      load(app.ActionFilesListBox_2.Value)
      assignin('base','GameActions',GameActions);
    end

    % Button pushed function: NewActionDataButton
    function newActionData(app, event)
      init_GameActions;
      assignin('base','GameActions',GameActions);
    end

    % Button pushed function: AddtoTeamAButton
    function add2TeamA(app, event)
      n = length(app.TeamAListBox.Items);
      entry = app.FullRosterListBox.Value;
      m = length(entry);
      for i = 1:m
        app.TeamAListBox.Items{n+i} = entry{i};        
      end
      app.TeamListBox.Value = 'A';
      app.PlayerListBox.Items = app.TeamAListBox.Items;
      n = length(app.PlayerListBox.Items);
      if n>0
        app.PlayerListBox.Value = app.PlayerListBox.Items(1);
      end
    end

    % Button pushed function: DeleteLastButtonA
    function Delete2TeamA(app, event)
      n = length(app.TeamAListBox.Items);
      if n > 0
        app.TeamAListBox.Items(n) = [];               
      end
      app.TeamListBox.Value = 'A';
      app.PlayerListBox.Items = app.TeamAListBox.Items;
      n = length(app.PlayerListBox.Items);
      if n>0
        app.PlayerListBox.Value = app.PlayerListBox.Items(1);
      end
    end

    % Button pushed function: AddtoTeamBButton
    function add2TeamB(app, event)
      n = length(app.TeamBListBox.Items);
      entry = app.FullRosterListBox.Value;
      m = length(entry);
      for i = 1:m
        app.TeamBListBox.Items{n+i} = entry{i};        
      end
      app.TeamListBox.Value = 'B';
      app.PlayerListBox.Items = app.TeamBListBox.Items;
      n = length(app.PlayerListBox.Items);
      if n>0
        app.PlayerListBox.Value = app.PlayerListBox.Items(1);
      end
    end

    % Button pushed function: DeleteLastButtonB
    function Delete2TeamB(app, event)
      n = length(app.TeamBListBox.Items);
      if n > 0
        app.TeamBListBox.Items(n) = [];               
      end
      app.TeamListBox.Value = 'B';
      app.PlayerListBox.Items = app.TeamBListBox.Items;
      n = length(app.PlayerListBox.Items);
      if n>0
        app.PlayerListBox.Value = app.PlayerListBox.Items(1);
      end
    end

    % Callback function
    function add2TeamC(app, event)
      n = length(app.TeamCListBox.Items);
      entry = app.FullRosterListBox.Value;
      m = length(entry);
      for i = 1:m
        app.TeamCListBox.Items{n+i} = entry{i};        
      end
      app.TeamListBox.Value = 'C';
      app.PlayerListBox.Items = app.TeamCListBox.Items;
      n = length(app.PlayerListBox.Items);
      if n>0
        app.PlayerListBox.Value = app.PlayerListBox.Items(1);
      end
    end

    % Callback function
    function Delete2TeamC(app, event)
      n = length(app.TeamCListBox.Items);
      if n > 0
        app.TeamCListBox.Items(n) = [];               
      end
      app.TeamListBox.Value = 'C';
      app.PlayerListBox.Items = app.TeamCListBox.Items;
      n = length(app.PlayerListBox.Items);
      if n>0
        app.PlayerListBox.Value = app.PlayerListBox.Items(1);
      end
    end

    % Value changed function: TeamListBox
    function showTeamPlayer(app, event)
      value = app.TeamListBox.Value;
      switch value
        case 'A'
          app.PlayerListBox.Items = app.TeamAListBox.Items;
        case 'B'
          app.PlayerListBox.Items = app.TeamBListBox.Items;
      end
      app.PlayerListBox.Value = app.PlayerListBox.Items(1);
    end

    % Value changed function: ActionListBox
    function changeDetails(app, event)
      value = app.ActionListBox.Value;
      switch value
        case 'Pass'
          app.Detail1ListBox.Items =  {'Regular', 'Threat', 'Assist', 'Missed'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Shoot'
          app.Detail1ListBox.Items =  {'FreeThrow', 'Layup', 'Close', '2ShortL', '2ShortTop', '2ShortR', '2LongLC', '2LongLW', '2LongTop', '2LongRW', '2LongRC', '3LC', '3LW', '3Top', '3RW', '3RC'};
          app.Detail2ListBox.Items =  {'miss', 'make'};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Rebound'
          app.Detail1ListBox.Items =  {'Defensive', 'Offensive'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Defense'
          app.Detail1ListBox.Items =  {'Steal', 'Block', 'Shot Contest', 'Shot Allowed'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Turnover'
          app.Detail1ListBox.Items =  {'Pass', 'Travel', 'Dribble', 'Bound', 'Foul'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
        case 'Other'
          app.Detail1ListBox.Items =  {'Off-ball screen', 'Screen assist'};
          app.Detail2ListBox.Items =  {' '};
          app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
          app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);
      end
    end

    % Button pushed function: AddtoActionsButton
    function add2Variable(app, event)
      
      % get base workspace var into local
      GameActions = evalin('base','GameActions');
      idx = size(GameActions,1)+1;
      
      % arrange inputs from App
      entry = {};
      entry{1} = app.TeamListBox.Value;
      entry{2} = app.PlayerListBox.Value;
      entry{3} = app.ActionListBox.Value;
      entry{4} = app.Detail1ListBox.Value;
      entry{5} = app.Detail2ListBox.Value;
      entry{6} = app.NotesEditField.Value;
      
      % add entry into database
      GameActions(idx,:) = entry;
      assignin('base','GameActions',GameActions);
      
      % reset some inputs fields after entering data
      app.NotesEditField.Value = "";
      
      % flash a light for visual
      for i = 1:3
        app.StatusLamp.Color = [0.8,0.8,0.8];
        pause(0.05)
        app.StatusLamp.Color = [0,0.6,1];
        pause(0.15)
      end
      app.StatusLamp.Color = [0,1,0];
      
      if app.Detail2ListBox.Value == "miss"
        app.ActionListBox.Value = 'Rebound';
        app.Detail1ListBox.Items =  {'Defensive', 'Offensive'};
        app.Detail2ListBox.Items =  {' '};
        app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
        app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);  
      end

      if app.Detail2ListBox.Value == "make"
        switch app.TeamListBox.Value
          case 'A'
            app.TeamListBox.Value = 'B';            
            app.PlayerListBox.Items = app.TeamBListBox.Items;
          case 'B'
            app.TeamListBox.Value = 'A';            
            app.PlayerListBox.Items = app.TeamAListBox.Items;    
        end
        app.PlayerListBox.Value = app.PlayerListBox.Items(1);
        app.ActionListBox.Value = 'Pass';
        app.Detail1ListBox.Items =  {'Regular', 'Threat', 'Assist', 'Missed'};
        app.Detail2ListBox.Items =  {' '};
        app.Detail1ListBox.Value = app.Detail1ListBox.Items(1);
        app.Detail2ListBox.Value = app.Detail2ListBox.Items(1);  
      end
      
      if app.Detail1ListBox.Value == "Assist"
        app.ActionListBox.Value = 'Shoot';
        app.Detail1ListBox.Items =  {'FreeThrow', 'Layup', 'Close', '2ShortL', '2ShortTop', '2ShortR', '2LongLC', '2LongLW', '2LongTop', '2LongRW', '2LongRC', '3LC', '3LW', '3Top', '3RW', '3RC'};
        app.Detail2ListBox.Items =  {'miss', 'make'};
        app.Detail1ListBox.Value = app.Detail1ListBox.Items(2);
        app.Detail2ListBox.Value = app.Detail2ListBox.Items(2);
        app.PlayerListBox.Value = app.PlayerListBox.Items(1);
      end      
    end

    % Button pushed function: DeleteLastEntryButton
    function DeleteLastEntry(app, event)
      % get base workspace var into local
      GameActions = evalin('base','GameActions');
      n = height(GameActions);
      if n > 1
        % delete last entry
        GameActions(end,:) = [];
        % update back into workspace
        assignin('base','GameActions',GameActions);        
      end
      % flash a light for visual
      for i = 1:2
        app.StatusLamp.Color = [0.8,0.8,0.8];
        pause(0.05)
        app.StatusLamp.Color = [1,0,0];
        pause(0.15)
      end
      app.StatusLamp.Color = [0,1,0];
    end

    % Button pushed function: SaveDatatoFileButton
    function save2File(app, event)
      proj = matlab.project.currentProject;
      GameActions = evalin('base','GameActions');            % get base workspace var into local
      save(strcat(proj.RootFolder,"\Data\",app.DatafileNameEditField.Value),"GameActions")
    end

    % Button pushed function: GenerateStatsButton
    function generateStats(app, event)
      filelist = app.ActionFilesListBox.Value;
      outfile  = app.StatsFilenameEditField.Value;
      func_GenerateStats(filelist,outfile);
      func_GenerateShotMap(filelist,outfile);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.TabGroup = uitabgroup(app.UIFigure);
      app.LoadNewDataTab = uitab(app.TabGroup);
      app.LoadNewDataTab.Title = 'Load/New Data';
      app.ListActionFilesButton_2 = uibutton(app.LoadNewDataTab, 'push');
      app.ListActionFilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ListActionFiles, true);
      app.ListActionFilesButton_2.Text = 'List Action Files';
      app.LoadActionDataButton = uibutton(app.LoadNewDataTab, 'push');
      app.LoadActionDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadActionData, true);
      app.LoadActionDataButton.Text = 'Load Action Data';
      app.ActionFilesListBox_2Label = uilabel(app.LoadNewDataTab);
      app.ActionFilesListBox_2Label.Text = 'Action Files';
      app.ActionFilesListBox_2 = uilistbox(app.LoadNewDataTab);
      app.ActionFilesListBox_2.Items = {};
      app.ActionFilesListBox_2.Value = {};
      app.LoadorcreatenewdatawilloverwritecurrentworkLabel = uilabel(app.LoadNewDataTab);
      app.LoadorcreatenewdatawilloverwritecurrentworkLabel.Text = 'Load or create new data will overwrite current work!!!';
      app.NewActionDataButton = uibutton(app.LoadNewDataTab, 'push');
      app.NewActionDataButton.ButtonPushedFcn = createCallbackFcn(app, @newActionData, true);
      app.NewActionDataButton.Text = 'New Action Data';
      app.StartfromScratchLabel = uilabel(app.LoadNewDataTab);
      app.StartfromScratchLabel.Text = 'Start from Scratch';
      app.ContinueWorkingLabel = uilabel(app.LoadNewDataTab);
      app.ContinueWorkingLabel.Text = 'Continue Working';
      app.ORLabel = uilabel(app.LoadNewDataTab);
      app.ORLabel.Text = 'OR';
      app.AssignPlayersTab = uitab(app.TabGroup);
      app.AssignPlayersTab.Title = 'Assign Players';
      app.FullRosterListBoxLabel = uilabel(app.AssignPlayersTab);
      app.FullRosterListBoxLabel.Text = 'Full Roster';
      app.FullRosterListBox = uilistbox(app.AssignPlayersTab);
      app.FullRosterListBox.Items = {'Alex_YXY ', 'Allen_GHH ', 'Andrew_Du ', 'Archie_ZYQ ', 'BK_FYP ', 'Bob_BKZ ', 'Bobby_ZCB', 'Bobo_PB ', 'Chione_XCY ', 'CUBA_LCY ', 'Daniel_XZY ', 'David_HQC ', 'Hou_HF ', 'Jam_LTJ ', 'JimBig_ZYJ ', 'JimLit_XQH ', 'Ken_BYX ', 'Kevin_MZQ ', 'Ma_LY ', 'Michael_CBC ', 'Neo_LWQ ', 'Nicole_LXR ', 'Paras_WXH ', 'Tang_YF ', 'Tony_ZBR ', 'Tyzer_TZX ', 'Wan_Yuxin ', 'Will_ZJW ', 'Yuan_XC ', 'Zhu_XY', ''};
      app.FullRosterListBox.Multiselect = 'on';
      app.FullRosterListBox.Value = {'Alex_YXY '};
      app.TeamAListBoxLabel = uilabel(app.AssignPlayersTab);
      app.TeamAListBoxLabel.Text = 'Team A';
      app.TeamAListBox = uilistbox(app.AssignPlayersTab);
      app.TeamAListBox.Items = {};
      app.TeamAListBox.Value = {};
      app.TeamBListBoxLabel = uilabel(app.AssignPlayersTab);
      app.TeamBListBoxLabel.Text = 'Team B';
      app.TeamBListBox = uilistbox(app.AssignPlayersTab);
      app.TeamBListBox.Items = {};
      app.TeamBListBox.Value = {};
      app.DeleteLastButtonA = uibutton(app.AssignPlayersTab, 'push');
      app.DeleteLastButtonA.ButtonPushedFcn = createCallbackFcn(app, @Delete2TeamA, true);
      app.DeleteLastButtonA.Text = 'Delete Last';
      app.DeleteLastButtonB = uibutton(app.AssignPlayersTab, 'push');
      app.DeleteLastButtonB.ButtonPushedFcn = createCallbackFcn(app, @Delete2TeamB, true);
      app.DeleteLastButtonB.Text = 'Delete Last';
      app.AddtoTeamAButton = uibutton(app.AssignPlayersTab, 'push');
      app.AddtoTeamAButton.ButtonPushedFcn = createCallbackFcn(app, @add2TeamA, true);
      app.AddtoTeamAButton.Text = 'Add to Team A';
      app.AddtoTeamBButton = uibutton(app.AssignPlayersTab, 'push');
      app.AddtoTeamBButton.ButtonPushedFcn = createCallbackFcn(app, @add2TeamB, true);
      app.AddtoTeamBButton.Text = 'Add to Team B';
      app.AddActionsTab = uitab(app.TabGroup);
      app.AddActionsTab.Title = 'Add Actions';
      app.AddtoActionsButton = uibutton(app.AddActionsTab, 'push');
      app.AddtoActionsButton.ButtonPushedFcn = createCallbackFcn(app, @add2Variable, true);
      app.AddtoActionsButton.Text = 'Add to Actions';
      app.ActionListBoxLabel = uilabel(app.AddActionsTab);
      app.ActionListBoxLabel.Text = 'Action';
      app.ActionListBox = uilistbox(app.AddActionsTab);
      app.ActionListBox.Items = {'Pass', 'Shoot', 'Rebound', 'Turnover', 'Defense', 'Other'};
      app.ActionListBox.ValueChangedFcn = createCallbackFcn(app, @changeDetails, true);
      app.ActionListBox.Value = 'Pass';
      app.NotesEditFieldLabel = uilabel(app.AddActionsTab);
      app.NotesEditFieldLabel.Text = 'Notes';
      app.NotesEditField = uieditfield(app.AddActionsTab, 'text');
      app.Detail1ListBoxLabel = uilabel(app.AddActionsTab);
      app.Detail1ListBoxLabel.Text = 'Detail1';
      app.Detail1ListBox = uilistbox(app.AddActionsTab);
      app.Detail1ListBox.Items = {'Regular', 'Threat', 'Assist', 'Missed'};
      app.Detail1ListBox.Value = 'Regular';
      app.Detail2ListBoxLabel = uilabel(app.AddActionsTab);
      app.Detail2ListBoxLabel.Text = 'Detail2';
      app.Detail2ListBox = uilistbox(app.AddActionsTab);
      app.Detail2ListBox.Items = {' '};
      app.Detail2ListBox.Value = ' ';
      app.StatusLampLabel = uilabel(app.AddActionsTab);
      app.StatusLampLabel.Text = 'Status';
      app.StatusLamp = uilamp(app.AddActionsTab);
      app.DeleteLastEntryButton = uibutton(app.AddActionsTab, 'push');
      app.DeleteLastEntryButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastEntry, true);
      app.DeleteLastEntryButton.Text = {'Delete '; 'Last Entry'};
      app.PlayerListBoxLabel = uilabel(app.AddActionsTab);
      app.PlayerListBoxLabel.Text = 'Player';
      app.PlayerListBox = uilistbox(app.AddActionsTab);
      app.PlayerListBox.Items = {};
      app.PlayerListBox.Value = {};
      app.TeamListBoxLabel = uilabel(app.AddActionsTab);
      app.TeamListBoxLabel.Text = 'Team';
      app.TeamListBox = uilistbox(app.AddActionsTab);
      app.TeamListBox.Items = {'A', 'B'};
      app.TeamListBox.ValueChangedFcn = createCallbackFcn(app, @showTeamPlayer, true);
      app.TeamListBox.Value = 'A';
      app.SaveDataTab = uitab(app.TabGroup);
      app.SaveDataTab.Title = 'Save Data';
      app.SaveDatatoFileButton = uibutton(app.SaveDataTab, 'push');
      app.SaveDatatoFileButton.ButtonPushedFcn = createCallbackFcn(app, @save2File, true);
      app.SaveDatatoFileButton.Text = 'Save Data to File';
      app.VariableNameGameActionsfixedLabel = uilabel(app.SaveDataTab);
      app.VariableNameGameActionsfixedLabel.Text = 'Variable Name: GameActions (fixed)';
      app.startwithActionegAction0909Game2matLabel = uilabel(app.SaveDataTab);
      app.startwithActionegAction0909Game2matLabel.Text = ' start with "Action", e.g. Action0909Game2.mat';
      app.DatafileNameEditField = uieditfield(app.SaveDataTab, 'text');
      app.DatafileNameEditField.Value = 'ActionDateContent.mat';
      app.DatafileNameEditFieldLabel = uilabel(app.SaveDataTab);
      app.DatafileNameEditFieldLabel.Text = 'Datafile Name:';
      app.GenerateStatsTab = uitab(app.TabGroup);
      app.GenerateStatsTab.Title = 'Generate Stats';
      app.ActionFilesListBoxLabel = uilabel(app.GenerateStatsTab);
      app.ActionFilesListBoxLabel.Text = 'Action Files';
      app.ActionFilesListBox = uilistbox(app.GenerateStatsTab);
      app.ActionFilesListBox.Items = {};
      app.ActionFilesListBox.Multiselect = 'on';
      app.ActionFilesListBox.Value = {};
      app.ListActionFilesButton = uibutton(app.GenerateStatsTab, 'push');
      app.ListActionFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ListActionFiles, true);
      app.ListActionFilesButton.Text = 'List Action Files';
      app.StatsFilenameEditFieldLabel = uilabel(app.GenerateStatsTab);
      app.StatsFilenameEditFieldLabel.Text = 'Stats Filename';
      app.StatsFilenameEditField = uieditfield(app.GenerateStatsTab, 'text');
      app.StatsFilenameEditField.Value = 'StatsTest';
      app.GenerateStatsButton = uibutton(app.GenerateStatsTab, 'push');
      app.GenerateStatsButton.ButtonPushedFcn = createCallbackFcn(app, @generateStats, true);
      app.GenerateStatsButton.Text = 'Generate Stats';
      app.Step1Label = uilabel(app.GenerateStatsTab);
      app.Step1Label.Text = 'Step 1';
      app.Step2Label = uilabel(app.GenerateStatsTab);
      app.Step2Label.Text = 'Step 2';
      app.Step3Label = uilabel(app.GenerateStatsTab);
      app.Step3Label.Text = 'Step 3';
      app.SaveactiondatabeforegeneratestatsLabel = uilabel(app.GenerateStatsTab);
      app.SaveactiondatabeforegeneratestatsLabel.Text = 'Save action data before generate stats!!!';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DataEntryV4

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end